classdef ICuserInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PrefillTableButton             matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        PrefillUseRecordingscollumnButton  matlab.ui.control.Button
        ElectrodedepthmEditField       matlab.ui.control.NumericEditField
        ElectrodedepthmEditFieldLabel  matlab.ui.control.Label
        ElectrodearraynameEditField    matlab.ui.control.EditField
        ElectrodearraynameEditFieldLabel  matlab.ui.control.Label
        CalibrationalreadyreadintoExpControlCheckBox  matlab.ui.control.CheckBox
        UITable                        matlab.ui.control.Table
        DoneExportButton               matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end


    properties (Access = public)
        data_user % save needed user data during call of the GUI
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            app.data_user.ee=ee;
            app.Label.Text = ee.ExpID;
            % prefilling  based on the ICME List is a separate function
            % so we have the choice between prefilling from the raw data (slow) or using the import button
            % for already processed data

        end

        % Button pushed function: DoneExportButton
        function DoneExportButtonPushed(app, event)
            Electrode.name=app.ElectrodearraynameEditField.Value;
            Electrode.depth=app.ElectrodedepthmEditField.Value;
            CalibrationDone=app.CalibrationalreadyreadintoExpControlCheckBox.Value;
            UT.data = app.UITable.Data;
            UT.fieldNames = app.UITable.ColumnName;
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),'ICME',strcat("ICUserInput_", app.data_user.ee.ExpID, ".mat"));
            save(in_dir_name,'UT','Electrode','CalibrationDone');
            delete(app.UIFigure)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),'ICME',strcat("ICUserInput_", app.data_user.ee.ExpID, ".mat"));
            try
                load(in_dir_name);
                app.CalibrationalreadyreadintoExpControlCheckBox.Value=CalibrationDone;
                app.ElectrodearraynameEditField.Value=Electrode.name;
                app.ElectrodedepthmEditField.Value=Electrode.depth;
                app.UITable.Data=UT.data;
                app.UITable.ColumnName = UT.fieldNames;
            end
        end

        % Button pushed function: PrefillUseRecordingscollumnButton
        function PrefillUseRecordingscollumnButtonPushed(app, event)
            % ToDo sort UseRecordings based on fiber position/filter if
            % only 1 recording is analysed per option
        end

        % Button pushed function: PrefillTableButton
        function PrefillTableButtonPushed(app, event)
            % since this takes a while it is a separate function only to be
            % pressed when the import button is not yet an option
            L =listIcme(app.data_user.ee);


            app.data_user.ICMEs=L;
            app.data_user.SeriesIDList = L.IC_SeriesID;
            table_data=app.UITable.Data;
            table_ColumnNames=app.UITable.ColumnName;

            % prepare Table
            % SeriesID,ExpType,Filter,Laser ID,d fiber [µm],pos cochlea,pos fiber,orientation,UseRecording
            t = cell(numel( app.data_user.SeriesIDList ),length(table_ColumnNames));
            for ii = 1 : numel(app.data_user.SeriesIDList)
                IC = loadIcme(icme(app.data_user.ee,L.IC_SeriesID(ii)));
                if ii==1 % try to load the hardware def file which is the same for all icmes
                    if  isfield(IC.ExpInfo, 'default_file')
                        hardware_info_filename=fullfile(ukonmap,IC.D.dir{:},IC.ExpInfo.default_file);
                        if isfile(hardware_info_filename)
                            fid = fopen(hardware_info_filename, 'r');
                            if fid == -1
                                error('Unable to open file');
                            end
                            hardwareInfo = struct();
                            % Loop through each line in the file
                            while ~feof(fid)
                                line = fgetl(fid);
                                % Remove any comments
                                line = strtrim(line);
                                if isempty(line) || strncmp(line, '%', 1)
                                    continue;
                                end
                                % Split the line into key and value
                                [split_line] = strsplit(line, '=');
                                key = strtrim(split_line{1});
                                value = strtrim(split_line{2});

                                % Store the value in the struct
                                hardwareInfo.(key) = strtrim(split(value,','));
                            end

                            % Close the file
                            fclose(fid);
                        else
                            hardwareInfo={};
                        end
                    else
                        hardwareInfo={};
                    end
                end
                t{ii,find(strcmp(table_ColumnNames,'SeriesID'))} = char(app.data_user.SeriesIDList(ii));
                t{ii,find(strcmp(table_ColumnNames,'ExpType'))} = IC.Stim.exp_type;
                if ~isempty(hardwareInfo)
                    % for OBIS lasers get the serial number and COM port from
                    % the hardware info
                    laser_counter=1;
                    for channel_ix=1:length(IC.ExpInfo.channel)
                        cur_channel=IC.ExpInfo.channel{channel_ix};

                        if contains(cur_channel,'laser')
                            ao_channel_name_list=hardwareInfo.ao_chan_name;
                            ao_channel_ix=find(ismember(ao_channel_name_list,cur_channel));
                            if contains(IC.Stim.exp_type,'OBIS')
                                ComPort=hardwareInfo.OBIS_port{str2num(hardwareInfo.obis_ComIndex{ao_channel_ix})};
                                SerialNr=hardwareInfo.OBIS_SerialNumber{str2num(hardwareInfo.obis_ComIndex{ao_channel_ix})};
                            elseif contains(IC.Stim.exp_type,'DarkRed')
                                ComPort=hardwareInfo.DarkRedLaser_port{1};
                                SerialNr='DRL';
                            elseif contains(IC.Stim.exp_type,'OXXIUS')
                                ComPort=hardwareInfo.OXXIUS_port;
                                SerialNr='OXXIUS';
                            else
                                ComPort='';
                                SerialNr='';
                            end
                            if laser_counter==1 % put first laser info in the standard laser info box
                                t{ii,find(contains(table_ColumnNames,'Laser/oCI ID'))}=SerialNr;
                                t{ii,find(contains(table_ColumnNames,'d fiber'))} ='200';
                                if isempty(find(contains(table_ColumnNames,'LaserCOMPort')))
                                    new_column=cell(numel( app.data_user.SeriesIDList ),1);
                                    t = [t(:, 1:find(contains(table_ColumnNames,'Laser/oCI ID'))) new_column t(:, find(contains(table_ColumnNames,'Laser/oCI ID'))+1:end)];
                                    table_ColumnNames= [table_ColumnNames(1:find(contains(table_ColumnNames,'Laser/oCI ID'))); {'LaserCOMPort'} ;table_ColumnNames(find(contains(table_ColumnNames,'Laser/oCI ID'))+1:end)];
                                end
                                t{ii,find(contains(table_ColumnNames,'LaserCOMPort'))}=ComPort;
                            else % create new laser collumns
                                lasername=['Laser',num2str(laser_counter)];
                                if isempty(find(contains(table_ColumnNames,sprintf('%s filter',lasername)))) % create all corresponding collumns at once
                                    new_column=cell(numel( app.data_user.SeriesIDList ),4);
                                    t = [t(:, 1:find(contains(table_ColumnNames,'d fiber'))) new_column t(:, find(contains(table_ColumnNames,'d fiber'))+1:end)];
                                    table_ColumnNames= [table_ColumnNames(1:find(contains(table_ColumnNames,'d fiber')));...
                                        { sprintf('%s filter',lasername); sprintf('%s ID',lasername);sprintf('%s ComPort',lasername);  sprintf('%s d [µm]',lasername)} ;...
                                        table_ColumnNames(find(contains(table_ColumnNames,'d fiber'))+1:end)];
                                end
                                t{ii,find(contains(table_ColumnNames,sprintf('%s filter',lasername)))}='';
                                t{ii,find(contains(table_ColumnNames,sprintf('%s ID',lasername)))}=SerialNr;
                                t{ii,find(contains(table_ColumnNames,sprintf('%s d [µm]',lasername)))} ='200';
                                t{ii,find(contains(table_ColumnNames,sprintf('%s ComPort',lasername)))}=ComPort;

                            end


                            laser_counter=laser_counter+1;
                        end

                    end
                end

                t{ii,contains(table_ColumnNames,'Use')} =0;


                if isfield(IC.ExpInfo, 'implant_ID') && ~isempty(IC.ExpInfo.implant_ID)
                    if ~iscell(IC.ExpInfo.implant_ID)
                        IC.ExpInfo.implant_ID = {IC.ExpInfo.implant_ID};
                    end
                    t{ii,find(contains(table_ColumnNames,'Laser/oCI ID'))}=IC.ExpInfo.implant_ID{1,1};

                end

            end

            % empty doubles are not allowed so replace with empty strings
            %           t(cellfun(@isempty, t)) = {' '};
            app.UITable.Data = t;
            app.UITable.ColumnName=table_ColumnNames;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            dindices = event.Indices;
            t =   app.UITable.Data;
            for ii = 1 : size(dindices,1)
                tempInd = dindices(ii,:);% I am not sure why this is happening...  Somehow I can not use that as indexing
                valueFirst = dindices(1,:);
                t{tempInd(1),tempInd(2)}= app.UITable.Data{valueFirst(1),valueFirst(2)};
            end
            app.UITable.Data=t;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.DoneExportButton = uibutton(app.UIFigure, 'push');
            app.DoneExportButton.ButtonPushedFcn = createCallbackFcn(app, @DoneExportButtonPushed, true);
            app.DoneExportButton.Text = 'Done & Export';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SeriesID'; 'ExpType'; 'Filter'; 'Laser/oCI ID'; 'LaserCOMPort'; 'd fiber [µm]'; 'pos cochlea'; 'pos fiber'; 'orientation'; 'UseRecording'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalibrationalreadyreadintoExpControlCheckBox = uicheckbox(app.UIFigure);
            app.CalibrationalreadyreadintoExpControlCheckBox.Text = 'Calibration already read into ExpControl';
            app.CalibrationalreadyreadintoExpControlCheckBox.Value = true;
            app.ElectrodearraynameEditFieldLabel = uilabel(app.UIFigure);
            app.ElectrodearraynameEditFieldLabel.Text = 'Electrode array name';
            app.ElectrodearraynameEditField = uieditfield(app.UIFigure, 'text');
            app.ElectrodearraynameEditField.Value = 'unknown';
            app.ElectrodedepthmEditFieldLabel = uilabel(app.UIFigure);
            app.ElectrodedepthmEditFieldLabel.Text = 'Electrode depth [µm]';
            app.ElectrodedepthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElectrodedepthmEditField.Value = -3300;
            app.PrefillUseRecordingscollumnButton = uibutton(app.UIFigure, 'push');
            app.PrefillUseRecordingscollumnButton.ButtonPushedFcn = createCallbackFcn(app, @PrefillUseRecordingscollumnButtonPushed, true);
            app.PrefillUseRecordingscollumnButton.Text = 'Prefill UseRecordings collumn';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PrefillTableButton = uibutton(app.UIFigure, 'push');
            app.PrefillTableButton.ButtonPushedFcn = createCallbackFcn(app, @PrefillTableButtonPushed, true);
            app.PrefillTableButton.Text = 'PrefillTable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICuserInput(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end