classdef berabrWaveGUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        STD3Button           matlab.ui.control.StateButton
        checkButton          matlab.ui.control.Button
        ClickyClickyButton   matlab.ui.control.Button
        ManualEntryButton    matlab.ui.control.Button
        timeEditField        matlab.ui.control.NumericEditField
        timeEditFieldLabel   matlab.ui.control.Label
        extrapolateButton    matlab.ui.control.StateButton
        InverseButton        matlab.ui.control.StateButton
        RESETButton          matlab.ui.control.Button
        inspectButton        matlab.ui.control.Button
        AcceptButton         matlab.ui.control.Button
        Label2               matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        metricsPlotButton    matlab.ui.control.Button
        IMPORTButton         matlab.ui.control.Button
        EXPORTButton         matlab.ui.control.Button
        BERAcubListBox       matlab.ui.control.ListBox
        BERACubListBoxLabel  matlab.ui.control.Label
        UIAxesA3             matlab.ui.control.UIAxes
        UIAxesA2             matlab.ui.control.UIAxes
        UIAxesA1             matlab.ui.control.UIAxes
        UIAxesA              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        berabr_in % berabr object
        %  berabr_in.B % Experiment
        data_user % Stimulus
        data_out % Feather
        temp_ax%temporary axes information
    end

    methods (Access = private)
        function [app]=plotABR_h(app,tmpAx,F,Ichoice,allColors)

            %get x
            x = F(Ichoice).t *1e3;
            y = F(Ichoice).ABR *1e6;
            tmpCL = allColors(Ichoice,:);

            plot(tmpAx,[-1,10],[0 0 ],'color',[0.7 0.7 0.7],'linewidth',1)
            hold(tmpAx,'on')
            hABR = plot(tmpAx,x,y,'color',tmpCL,'linewidth',3);
            %plot wave data if available
            for ii = 1 : numel( app.data_out.Waves(Ichoice).t)
                hABR = plot(tmpAx,app.data_out.Waves(Ichoice).t(ii),app.data_out.Waves(Ichoice).A(ii),'ok');
            end
            %          plot(tmpAx,app.,y,'color',tmpCL,'linewidth',3);
            %
            hold(tmpAx,'off')
            ylabel(tmpAx,'Amplitude [\muV]','interpreter','Tex')
            xlabel(tmpAx,'Time [ms]')
            enableDefaultInteractivity(tmpAx)
            if strcmp(app.berabr_in.B.Stim(1).modality,'Acoustic')==1
                xlim(tmpAx,[-1 7])
            else
                xlim(tmpAx,[-1 5])
            end
            set(tmpAx,'Xlabel',[])
            set(tmpAx,'xgrid','on')
            set(tmpAx,'xminorgrid','on')
            set(tmpAx,'ygrid','on')
            title(tmpAx,app.BERAcubListBox.Items(Ichoice))
            app.temp_ax.hABR = hABR;
            app.temp_ax.tempChoice = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, B)

            %replace [] with nan
            for ii = 1 : B.nTraces
                if isempty(B.F(ii).ABR)
                    B = nanBerabr (B, ii);
                end
            end

            app.berabr_in.B = B;

            %change the listbox names
            temp_items=cell(0);
            for ii = 1 : app.berabr_in.B.nTraces
                temp_items{ii} = num2str(ii);
            end
            app.BERAcubListBox.Items = temp_items;
            %plot all the data in UIaxesA
            color_temp = jet(app.berabr_in.B.nTraces);
            app.temp_ax.color_temp = color_temp;

            hold(app.UIAxesA,'on');
            for ii = 1:app.berabr_in.B.nTraces
                plot(app.UIAxesA, app.berabr_in.B.F(ii).t*1e3, app.berabr_in.B.F(ii).ABR*1e6,'color', color_temp(ii,:),'linewidth',1.5);
            end
            plot(app.UIAxesA,[-1,10],[0 0 ],'color',[0.7 0.7 0.7],'linewidth',1)
            hold(app.UIAxesA,'off');

            ylabel(app.UIAxesA,'Amplitude [\muV]','interpreter','Tex')
            xlabel(app.UIAxesA,'Time [ms]')
            xlim(app.UIAxesA,[-1 10])
            set(app.UIAxesA,'xgrid','on')
            set(app.UIAxesA,'xminorgrid','on')
            set(app.UIAxesA,'ygrid','on')

            if app.berabr_in.B.Stim(1).protocol~=' '
                switch app.berabr_in.B.Stim(1).protocol
                    case 'I'
                        for ii = 1:app.berabr_in.B.nTraces
                            lgd_str{ii} = num2str(app.berabr_in.B.Stim(ii).intensity);
                        end
                    case 'D'
                        for ii = 1:app.berabr_in.B.nTraces
                            lgd_str{ii} = num2str(app.berabr_in.B.Stim(ii).duration);
                        end
                    case 'R'
                        for ii = 1:app.berabr_in.B.nTraces
                            lgd_str{ii} = num2str(app.berabr_in.B.Stim(ii).repRate);
                        end
                end
                temp_items=cell(0);
                for ii = 1 :numel(lgd_str)
                    temp_items{ii} =  lgd_str{ii};
                end
                app.BERAcubListBox.Items = temp_items;

            else
                lgd_str = '';
                temp_items=cell(0);
                for ii = 1 :numel(app.berabr_in.B.F)
                    temp_items{ii} = num2str(ii);
                end
                app.BERAcubListBox.Items = temp_items;
            end

           % out_ttlstr =   ttlString(B);
            lg = legend(app.UIAxesA,lgd_str,'Position', [    0.6897    0.7534    0.0855    0.1900]);%
            stringOut = ttlString(B);
            title(lg,stringOut.prtclMeasUnit)
            app.data_user.lgd_str = lgd_str;
            %make the title where we can see the emperiment information
            title(app.UIAxesA,stringOut.E_SID_P_H_M)
            %             exp_string = (app.berabr_in.B.Stim(1).lgnd);
            %             app.Label.Text = char(exp_string);

            app.UITable.Data = nan(2,5);
            app.UITable.RowName = {'P','N'};
            %        app.UITs
            %make the table values
            for ii = 1:numel(app.berabr_in.B.F)
                %data that will be exported.
                app.data_out.Waves(ii).t=nan(2,5);%timestamp
                app.data_out.Waves(ii).A=nan(2,5);%Amplitude stamp
                app.data_out.Waves(ii).ii=nan(2,5);%index stamp
                %data of the user. Once accepted, they go to the data_out
                app.data_user.Waves(ii).t=nan(2,5);%timestamp
                app.data_user.Waves(ii).A=nan(2,5);%Amplitude stamp
                app.data_user.Waves(ii).ii=nan(2,5);%index stamp

                %add the name of the experiment and the seriesID
                app.data_out.Waves(ii).ExpID = app.berabr_in.B.ExpID;
                app.data_out.Waves(ii).SeriesID = app.berabr_in.B.SeriesID;
  
            end
            if numel(app.BERAcubListBox.Items) ==1
                BERAcubListBoxValueChanged(app, 1)
            end
            %%STD value

            ms2numel = 150; %2ms are represented by 150number of elements, seconds 7:10
            concatinatedABRvector = nan(ms2numel*app.berabr_in.B.nTraces,1,1);
            for ii = 1 : app.berabr_in.B.nTraces
                concatinatedABRvector(1+ms2numel*(ii-1):ms2numel*(ii)) = app.berabr_in.B.F(ii).ABR (end-ms2numel+1:end);
            end
            ABR_trace_STD = std(concatinatedABRvector)*1e6;

            app.data_out.STD = ABR_trace_STD;
            app.data_out.STD3 = 3*ABR_trace_STD;
        end

        % Value changed function: BERAcubListBox
        function BERAcubListBoxValueChanged(app, event)

            [~,allChoices,IB]=intersect(app.BERAcubListBox.Items,app.BERAcubListBox.Value);
            allChoices = sortAccord(allChoices,IB);%those steps are needed to maintain the order that the choices were clicked by the user.
            %erase the temporary choice of ginput when showing a new bera
            % no choince in the table
            app.UITable.Selection=[];
            for ii = 1 : numel(IB)
                switch IB(ii)
                    case 1
                        plotABR_h(app,app.UIAxesA1,app.berabr_in.B.F,allChoices(IB(ii)), app.temp_ax.color_temp);
                        app.data_user.Ichoice = allChoices(IB(ii));
                    case 2
                        plotABR_h(app,app.UIAxesA2,app.berabr_in.B.F,allChoices(IB(ii)), app.temp_ax.color_temp);
                    case 3
                        plotABR_h(app,app.UIAxesA3,app.berabr_in.B.F,allChoices(IB(ii)), app.temp_ax.color_temp);
                end
            end
            %
            app.data_user.ginputCoordinates.t = nan;
            app.data_user.ginputCoordinates.ii = nan; %this is the indexstamp
            app.data_user.ginputCoordinates.A = nan; %this is the amplitudestamp
            temp_choice_text=horzcat('Time:', num2str(app.data_user.ginputCoordinates.t), ', Amplitude:', num2str(app.data_user.ginputCoordinates.A));
            app.Label2.Text = temp_choice_text;
            app.UITable.Data = app.data_out.Waves(app.data_user.Ichoice).t;

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %show the data in the table
            app.UITable.Data(event.Indices(1),event.Indices(2)) = app.data_user.ginputCoordinates.t;
            %    pass it in the data_user Waves
            app.data_user.Waves(app.data_user.Ichoice).t(event.Indices(1),event.Indices(2)) = app.data_user.ginputCoordinates.t;
            app.data_user.Waves(app.data_user.Ichoice).A(event.Indices(1),event.Indices(2)) = app.data_user.ginputCoordinates.A;
            app.data_user.Waves(app.data_user.Ichoice).ii(event.Indices(1),event.Indices(2)) = app.data_user.ginputCoordinates.ii;
            app.UITable.Selection = [];


        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)

            %find the values for whicht he user actually made a choice. (~isnan)
            user_wave_choice = ~isnan(app.data_user.Waves(app.data_user.Ichoice).t);
            app.data_out.Waves(app.data_user.Ichoice).t(user_wave_choice) = app.data_user.Waves(app.data_user.Ichoice).t(user_wave_choice);
            app.data_out.Waves(app.data_user.Ichoice).A(user_wave_choice) = app.data_user.Waves(app.data_user.Ichoice).A(user_wave_choice);
            app.data_out.Waves(app.data_user.Ichoice).ii(user_wave_choice) = app.data_user.Waves(app.data_user.Ichoice).ii(user_wave_choice);


        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            %  if isfolder(app.berabr_in.B.E.ProcessedDataDir) == 0
            %     mkdir(file_dir);
            % end
            W = app.data_out.Waves;
            save(fullfile(expProcDataDir,strcat("W_",app.berabr_in.B.ExpID,"_",string(app.berabr_in.B(1).SeriesID), ".mat")),'W');

        end

        % Button pushed function: IMPORTButton
        function IMPORTButtonPushed(app, event)
            temp =  load(fullfile(expProcDataDir,strcat("W_",app.berabr_in.B.ExpID,"_",string(app.berabr_in.B(1).SeriesID), ".mat")));
            app.data_out.Waves = temp.W;
        end

        % Value changed function: InverseButton
        function InverseButtonValueChanged(app, event)
            if numel( app.BERAcubListBox.Items) == 1
                BERAcubListBoxValueChanged(app, 1)
            end            % inverse the ABR data
            % for ii = 1 : size(app.berabr_in.B.F,2)
            app.berabr_in.B = inverseBerabr (app.berabr_in.B);
            % end
            color_temp = app.temp_ax.color_temp;

            %plot the ABR data
            hold(app.UIAxesA,'off');
            for ii = 1: size(app.berabr_in.B.F,2)
                plot(app.UIAxesA, app.berabr_in.B.F(ii).t*1e3, app.berabr_in.B.F(ii).ABR*1e6,'color', color_temp(ii,:),'linewidth',1.5);
                hold(app.UIAxesA,'on');

            end
            plot(app.UIAxesA,[-1,10],[0 0 ],'color',[0.7 0.7 0.7],'linewidth',1)
            hold(app.UIAxesA,'off');

            ylabel(app.UIAxesA,'Amplitude [\muV]','interpreter','Tex')
            xlabel(app.UIAxesA,'Time [ms]')
            xlim(app.UIAxesA,[-1 10])
            set(app.UIAxesA,'xgrid','on')
            set(app.UIAxesA,'xminorgrid','on')
            set(app.UIAxesA,'ygrid','on')

            lg = legend(app.UIAxesA,app.data_user.lgd_str,'Position', [0.7050 0.7870 0.0696 0.1515]);
            title(lg,app.berabr_in.B.Stim(1).unit);
            if numel(  app.BERAcubListBox.Items) ==1
                BERAcubListBoxValueChanged(app, 1)
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.data_user.ginputCoordinates.t = nan;
            app.data_user.ginputCoordinates.ii = nan; %this is the indexstamp
            app.data_user.ginputCoordinates.A = nan; %this is the amplitudestamp
            app.Label2.Text = 'USER INPUT DELETED';

            app.UITable.Data = nan(2,5);

            app.data_out.Waves(app.data_user.Ichoice).t(:) =nan;
            app.data_out.Waves(app.data_user.Ichoice).A(:) =nan;
            app.data_out.Waves(app.data_user.Ichoice).ii(:) = nan;

        end

        % Button pushed function: metricsPlotButton
        function metricsPlotButtonPushed(app, event)
            if strcmp(app.berabr_in.B.Stim(1).modality ,'Optical')==1
                int_xlabel = 'Intensity (mW)';
            elseif strcmp(app.berabr_in.B.Stim(1).modality ,'Acoustic')==1
                int_xlabel = 'SPL (dB)';
            end

            figure();

            color_temp = app.temp_ax.color_temp;
            wv = app.data_out.Waves;
            intensity = [app.berabr_in.B.Stim.intensity];
            repRate = [app.berabr_in.B.Stim.repRate];
            durat = [app.berabr_in.B.Stim.duration];

            %
            subplot (3,3,1)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(intensity(ii),wv(ii).t(1,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:),'MarkerEdgeColor','k'  );
            end
            hold off
            xlabel(int_xlabel)
            ylabel('Latency [ms]')

            subplot (3,3,4)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(intensity(ii),wv(ii).A(1,1)-wv(ii).A(2,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel(int_xlabel)
            ylabel('P_1-N_1  [\muV]')

            subplot (3,3,7)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(intensity(ii),wv(ii).A(1,2)-wv(ii).A(2,2),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel(int_xlabel)
            ylabel('P_2-N_2  [\muV]')
            %

            subplot (3,3,2)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(durat(ii),wv(ii).t(1,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('Duration [ms]')
            ylabel('Latency [ms]')
            ttl = ttlString(app.berabr_in.B);
            title(ttl.ExpSeriesModStim)

            subplot (3,3,5)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(durat(ii),wv(ii).A(1,1)-wv(ii).A(2,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('Duration [ms]')
            ylabel('P_1-N_1  [\muV]')

            subplot (3,3,8)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(durat(ii),wv(ii).A(1,2)-wv(ii).A(2,2),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('Duration [ms]')
            ylabel('P_2-N_2  [\muV]')
            %
            subplot (3,3,3)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(repRate(ii),wv(ii).t(1,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('repRate [Hz]')
            ylabel('Latency [ms]')

            subplot (3,3,6)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(repRate(ii),wv(ii).A(1,1)-wv(ii).A(2,1),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('repRate [Hz]')
            ylabel('P_1-N_1  [\muV]')

            subplot (3,3,9)
            hold on
            for ii = 1 : size(wv,2)
                h(ii) = plot(repRate(ii),wv(ii).A(1,2)-wv(ii).A(2,2),  'marker','o', 'MarkerFaceColor', color_temp(ii,:) ,'MarkerEdgeColor','k' );
            end
            hold off
            xlabel('repRate [Hz]')
            ylabel('P_2-N_2  [\muV]')
        end

        % Value changed function: extrapolateButton
        function extrapolateButtonValueChanged(app, event)

        end

        % Callback function
        function UITable2CellEdit(app, event)

        end

        % Button pushed function: ManualEntryButton
        function ManualEntryButtonPushed(app, event)
            Ichoice = app.data_user.Ichoice;
            app.data_user.ginputCoordinates.t = app.timeEditField.Value;

            ydata = app.berabr_in.B.F(app.data_user.Ichoice).ABR*1e6;

            [~, indexOfMin] = min(abs(Ichoice - app.data_user.ginputCoordinates.t));
            app.data_user.ginputCoordinates.indexOfMin = nan; %this is the indexstamp
            app.data_user.ginputCoordinates.A = ydata(indexOfMin);
            %this is the amplitudestamp
            temp_choice_text=horzcat('Time:', num2str(app.data_user.ginputCoordinates.t), ', Amplitude:', num2str(app.data_user.ginputCoordinates.A));
            app.Label2.Text = temp_choice_text;
        end

        % Button pushed function: inspectButton
        function inspectButtonPushed(app, event)
            figure()
            subplot (3,1,1)
            hold on
            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[app.data_out.STD3 app.data_out.STD3 ],'color', [0.7 0.7 0.7],'linewidth',1.5 )
            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[0 0 ],'color',[0.8 0.8 0.8],'linewidth',1.5)
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABR*1e6,'b')
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABR*1e6,'.k')
            ST = ttlString(app.berabr_in.B);
            legend ("std3")
            ylabel('Amplitude [\muV]','interpreter','Tex')
            title([strcat("Ichoice: ",num2str(app.data_user.Ichoice),", ABR, "),ST.ExpSeriesModStim,strcat("filter cutoff:",num2str(app.berabr_in.B.F(app.data_user.Ichoice).filter))])
            set(gca,'xgrid','on')
            set(gca,'xminorgrid','on')
            set(gca,'ygrid','on')
            hold off

            subplot (3,1,2)
            hold on
            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[app.data_out.STD3 app.data_out.STD3 ],'color', [0.7 0.7 0.7] ,'linewidth',1.5)

            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[0 0 ],'color',[0.8 0.8 0.8],'linewidth',1.5)
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABReven*1e6,'b')
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABReven*1e6,'.k')
            hold off
            title('ABReven')
            ylabel('Amplitude [\muV]','interpreter','Tex')
            set(gca,'xgrid','on')
            set(gca,'xminorgrid','on')
            set(gca,'ygrid','on')
            hold off

            subplot (3,1,3)
            hold on
            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[app.data_out.STD3 app.data_out.STD3 ],'color', [0.7 0.7 0.7] ,'linewidth',1.5)

            plot(minmax(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3),[0 0 ],'color',[0.8 0.8 0.8],'linewidth',1.5)
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABRodd*1e6,'b')
            plot(app.berabr_in.B.F(app.data_user.Ichoice).t*1e3,app.berabr_in.B.F(app.data_user.Ichoice).ABRodd*1e6,'.k')
            hold off
            title('ABRodd')

            ylabel('Amplitude [\muV]','interpreter','Tex')
            xlabel('Time [ms]')
            set(gca,'xgrid','on')
            set(gca,'xminorgrid','on')
            set(gca,'ygrid','on')
            hold off
        end

        % Button pushed function: ClickyClickyButton
        function clickWave(app, event)
            crd = get (app.UIAxesA1, 'CurrentPoint');
            %  fprintf('CurrentLoc is: %f, %f\n', crd(1,1), crd(1,2))
            x = crd(1,1);
            app.data_user.ginputCoordinates.t = x; %this is the timestamp

            %this is the index stamp
            xdata = app.berabr_in.B.F(app.data_user.Ichoice).t*1e3;
            ydata = app.berabr_in.B.F(app.data_user.Ichoice).ABR*1e6;

            [~, indexOfMin] = min(abs(xdata-x));
            % here I will add the extrapollation
            if app.extrapolateButton.Value == 1
                windowWidth = 7;
                allIndex = indexOfMin-windowWidth:indexOfMin+windowWidth;
                slWindow =  ydata(allIndex);%test convex/concave
                if mean(diff(diff(slWindow)))>0
                    [~,LOCS]=findpeaks(-(slWindow),'NPeaks',1);

                elseif mean(diff(diff(slWindow)))<0
                    [~,LOCS]=findpeaks(slWindow,'NPeaks',1);
                end
                indexOfMin = allIndex(LOCS);
                x = xdata(indexOfMin);
            end
            x = xdata(indexOfMin);

            %
            app.data_user.ginputCoordinates.t= x; %this is the timestamp

            app.data_user.ginputCoordinates.ii = indexOfMin; %this is the indexstamp
            app.data_user.ginputCoordinates.A = ydata(indexOfMin); %this is the amplitudestamp
            %
            temp_choice_text=horzcat('Time:', num2str(app.data_user.ginputCoordinates.t), ', Amplitude:', num2str(app.data_user.ginputCoordinates.A));
            app.Label2.Text = temp_choice_text;
            yy = ylim(app.UIAxesA1);
            %show the temporary choice
            hold (app.UIAxesA1,'on')
            try
                h_temp(1) = plot(app.UIAxesA1,[x,x],yy,'color','k','linewidth',1.5);
                h_temp(2) = plot(app.UIAxesA1,[x,x],[app.data_user.ginputCoordinates.A app.data_user.ginputCoordinates.A],'ok');
                hold (app.UIAxesA1,'off')
                %store the temporary choice axis
                app.temp_ax.tempChoice = h_temp;
            catch
                warning("click again, there is no local min/max where you are clicking. Otherwise, deactivate extrapolation or go manual. You are welcome :)")
            end
            % update the table >> click on the UITable       %     roiPOC = drawpoint(app.UIAxesA1);    %Use Mouse To Select a point ROI


        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            %three tests will be implemented. Data gap, latency and
            %Amplitude.
            for ii = 1 : app.berabr_in.B.nTraces
                matrix1Dt = reshape(app.data_out.Waves(ii).t , [numel(app.data_out.Waves(ii).t),1]);
                matrix1DA = reshape(app.data_out.Waves(ii).A , [numel(app.data_out.Waves(ii).A),1]);

                %test A: data Gap!
                if sum(isnan(matrix1Dt(1:4)))>0
                    warning(strcat("W1 and W2 data gap in trace:" ,num2str(ii)));
                end
                %test B: latencies!
                if min(denan(diff(matrix1Dt)))<=0
                    warning(strcat("Problem with the latencies in trace:" ,num2str(ii)));
                end
                %test C: amplitude!
                if min(abs(denan(diff(matrix1DA))))<=app.data_out.STD3
                    warning(strcat("P2N amplitude lower than 3*STD in trace:" ,num2str(ii)));
                end
            end


        end

        % Value changed function: STD3Button
        function STD3ButtonValueChanged(app, event)
            value = app.STD3Button.Value;
            if value == 1
                %plot the STD in all the UIAxes
                hold(app.UIAxesA,'on');
                std3h0=plot(app.UIAxesA,[-1 10], [app.data_out.STD3 app.data_out.STD3], "k");
                hold(app.UIAxesA,'off');
                tmplg = get(app.UIAxesA,"legend");
                tmplgstr = tmplg.String;
                tmplgstr{end} = 'STD3';
                set(tmplg,"String",tmplgstr);

                hold(app.UIAxesA1,'on');
                std3h1= plot(app.UIAxesA1,[-1 10], [app.data_out.STD3 app.data_out.STD3], "k");
                hold(app.UIAxesA1,'off');

                hold(app.UIAxesA2,'on');
                std3h2 = plot(app.UIAxesA2,[-1 10], [app.data_out.STD3 app.data_out.STD3], "k");
                hold(app.UIAxesA2,'off');

                hold(app.UIAxesA3,'on');
                std3h3 = plot(app.UIAxesA3,[-1 10], [app.data_out.STD3 app.data_out.STD3], "k");
                hold(app.UIAxesA3,'off');

                app.data_user.std3handles =[std3h0,std3h1,std3h2,std3h3];
            elseif value ==0

                delete(app.data_user.std3handles);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesA = uiaxes(app.UIFigure);
            title(app.UIAxesA, 'All ABR')
            xlabel(app.UIAxesA, 'X')
            ylabel(app.UIAxesA, 'Y')
            zlabel(app.UIAxesA, 'Z')
            app.UIAxesA1 = uiaxes(app.UIFigure);
            title(app.UIAxesA1, 'Title')
            ylabel(app.UIAxesA1, 'Y')
            zlabel(app.UIAxesA1, 'Z')
            app.UIAxesA2 = uiaxes(app.UIFigure);
            title(app.UIAxesA2, 'Title')
            xlabel(app.UIAxesA2, 'X')
            ylabel(app.UIAxesA2, 'Y')
            zlabel(app.UIAxesA2, 'Z')
            app.UIAxesA3 = uiaxes(app.UIFigure);
            title(app.UIAxesA3, 'Title')
            xlabel(app.UIAxesA3, 'X')
            ylabel(app.UIAxesA3, 'Y')
            zlabel(app.UIAxesA3, 'Z')
            app.BERACubListBoxLabel = uilabel(app.UIFigure);
            app.BERACubListBoxLabel.Text = 'BERACub';
            app.BERAcubListBox = uilistbox(app.UIFigure);
            app.BERAcubListBox.Multiselect = 'on';
            app.BERAcubListBox.ValueChangedFcn = createCallbackFcn(app, @BERAcubListBoxValueChanged, true);
            app.BERAcubListBox.Value = {'Item 1'};
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.IMPORTButton = uibutton(app.UIFigure, 'push');
            app.IMPORTButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTButtonPushed, true);
            app.IMPORTButton.Text = 'IMPORT';
            app.metricsPlotButton = uibutton(app.UIFigure, 'push');
            app.metricsPlotButton.ButtonPushedFcn = createCallbackFcn(app, @metricsPlotButtonPushed, true);
            app.metricsPlotButton.Text = 'metricsPlot';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'W1'; 'W2'; 'W3'; 'W4'; 'W5'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.inspectButton = uibutton(app.UIFigure, 'push');
            app.inspectButton.ButtonPushedFcn = createCallbackFcn(app, @inspectButtonPushed, true);
            app.inspectButton.Text = 'inspect';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET?';
            app.InverseButton = uibutton(app.UIFigure, 'state');
            app.InverseButton.ValueChangedFcn = createCallbackFcn(app, @InverseButtonValueChanged, true);
            app.InverseButton.Text = 'Inverse?';
            app.extrapolateButton = uibutton(app.UIFigure, 'state');
            app.extrapolateButton.ValueChangedFcn = createCallbackFcn(app, @extrapolateButtonValueChanged, true);
            app.extrapolateButton.Text = 'extrapolate?';
            app.extrapolateButton.Value = true;
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ManualEntryButton = uibutton(app.UIFigure, 'push');
            app.ManualEntryButton.ButtonPushedFcn = createCallbackFcn(app, @ManualEntryButtonPushed, true);
            app.ManualEntryButton.Text = 'Manual Entry';
            app.ClickyClickyButton = uibutton(app.UIFigure, 'push');
            app.ClickyClickyButton.ButtonPushedFcn = createCallbackFcn(app, @clickWave, true);
            app.ClickyClickyButton.Text = 'ClickyClicky';
            app.checkButton = uibutton(app.UIFigure, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.STD3Button = uibutton(app.UIFigure, 'state');
            app.STD3Button.ValueChangedFcn = createCallbackFcn(app, @STD3ButtonValueChanged, true);
            app.STD3Button.Text = 'STD3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = berabrWaveGUI2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end