classdef chooseHistImgToUse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        PrefillButton  matlab.ui.control.Button
        ImportButton   matlab.ui.control.Button
        Label          matlab.ui.control.Label
        choosewhichhistologyimagestouseLabel  matlab.ui.control.Label
        DoneButton     matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end

    
    properties (Access = public)
        data_user % save needed user data during call of the GUI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            
            % load HistImgs list
            HistImgs=listHistImg(ee,0);

            % prefill the use based on versions
            for i = 1:length(HistImgs.Sides)
                for j = 1:length(HistImgs.Turns)
                    max_version = max(HistImgs.Versions(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j)));
                    try
                        HistImgs.UseImage(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j) & HistImgs.Versions ~= max_version) = 0;
                    end
                    try
                        HistImgs.UseImage(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j) & HistImgs.Versions == max_version) = 1;
                    end
                end
            end
            app.Label.Text = ee.ExpID;
            app.data_user.HistImgs=HistImgs;
            app.data_user.ee=ee;
            app.data_user.SeriesIDList = app.data_user.HistImgs.HistImg_SeriesID;

            % Add data to table
            t = cell(numel( app.data_user.SeriesIDList ),4);
            for ii = 1 : numel(app.data_user.SeriesIDList)
                t{ii,1} = char(app.data_user.SeriesIDList(ii));%seriesID
                t{ii,2} = char(app.data_user.HistImgs.Sides(ii));%filterOD
                t{ii,3} = char(app.data_user.HistImgs.Turns(ii));

                t{ii,4} = 0;

            end
            app.UITable.Data=t;


        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            dindices = event.Indices;
            t =   app.UITable.Data;
            for ii = 1 : size(dindices,1)
                tempInd = dindices(ii,:);% I am not sure why this is happening...  Somehow I can not use that as indexing
                valueFirst = dindices(1,:);
                t{tempInd(1),tempInd(2)}= app.UITable.Data{valueFirst(1),valueFirst(2)};
            end
            app.UITable.Data=t;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            HistoTable.data = app.UITable.Data;
            HistoTable.fieldNames = app.UITable.ColumnName;
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),'HISTO',strcat("HistoUserInput_", app.data_user.ee.ExpID, ".mat"));
            save(in_dir_name,'HistoTable');
            delete(app.UIFigure)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),'HISTO',strcat("HistoUserInput_", app.data_user.ee.ExpID, ".mat"));
            try
                load(in_dir_name);
                app.UITable.Data=HistoTable.data;
                app.UITable.ColumnName = HistoTable.fieldNames;
            end
        end

        % Button pushed function: PrefillButton
        function PrefillButtonPushed(app, event)
            % load HistImgs list
            HistImgs=app.data_user.HistImgs;
            
            % prefill the use based on versions
            for i = 1:length(HistImgs.Sides)
                for j = 1:length(HistImgs.Turns)
                    max_version = max(HistImgs.Versions(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j)));
                    try
                        HistImgs.UseImage(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j) & HistImgs.Versions ~= max_version) = 0;
                    end
                    try
                        HistImgs.UseImage(HistImgs.Sides == HistImgs.Sides(i) & HistImgs.Turns == HistImgs.Turns(j) & HistImgs.Versions == max_version) = 1;
                    end
                end
            end

            app.data_user.HistImgs=HistImgs;

            % Add data to table
            t = cell(numel( app.data_user.SeriesIDList ),4);
            for ii = 1 : numel(app.data_user.SeriesIDList)
                t{ii,1} = char(app.data_user.SeriesIDList(ii));%seriesID
                t{ii,2} = char(app.data_user.HistImgs.Sides(ii));%filterOD
                t{ii,3} = char(app.data_user.HistImgs.Turns(ii));

                t{ii,4} = app.data_user.HistImgs.UseImage(ii);

            end
            app.UITable.Data=t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'HistImgSeriesID'; 'Side'; 'Turn'; 'Use'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.choosewhichhistologyimagestouseLabel = uilabel(app.UIFigure);
            app.choosewhichhistologyimagestouseLabel.Text = 'choose which histology images to use';
            app.Label = uilabel(app.UIFigure);
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PrefillButton = uibutton(app.UIFigure, 'push');
            app.PrefillButton.ButtonPushedFcn = createCallbackFcn(app, @PrefillButtonPushed, true);
            app.PrefillButton.Text = 'Prefill';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseHistImgToUse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end