classdef exploreBerabr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        crossCorButton        matlab.ui.control.Button
        clickWavesButton      matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        ABRberasListBox       matlab.ui.control.ListBox
        ABRberasListBoxLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        data_user % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            L = listBerabr(ee);
            app.ABRberasListBox.Items = L.ABR_SeriesID;
            app.Label.Text = ee.ExpID;
            app.data_user.ee = ee;
        end

        % Button pushed function: clickWavesButton
        function clickWavesButtonPushed(app, event)
            B = loadBerabr(berabr (app.data_user.ee, app.ABRberasListBox.Value ));
            berabrWaveGUI2(B);
        end

        % Button pushed function: crossCorButton
        function crossCorButtonPushed(app, event)
            B = loadBerabr(berabr (app.data_user.ee, app.ABRberasListBox.Value ));
            crossCorThreshold(B);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ABRberasListBoxLabel = uilabel(app.UIFigure);
            app.ABRberasListBoxLabel.Text = 'ABR beras';
            app.ABRberasListBox = uilistbox(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.clickWavesButton = uibutton(app.UIFigure, 'push');
            app.clickWavesButton.ButtonPushedFcn = createCallbackFcn(app, @clickWavesButtonPushed, true);
            app.clickWavesButton.Text = 'clickWaves';
            app.crossCorButton = uibutton(app.UIFigure, 'push');
            app.crossCorButton.ButtonPushedFcn = createCallbackFcn(app, @crossCorButtonPushed, true);
            app.crossCorButton.Text = 'crossCor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreBerabr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end