classdef exploreSU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SPpPButton              matlab.ui.control.Button
        PSTHButton              matlab.ui.control.Button
        FSLButton               matlab.ui.control.Button
        CVButton                matlab.ui.control.Button
        ISIButton               matlab.ui.control.Button
        EntrainmentIndexButton  matlab.ui.control.Button
        VSButton                matlab.ui.control.Button
        CLOSEALLButton          matlab.ui.control.Button
        rasterPlotButton        matlab.ui.control.Button
        stimulusSampleButton    matlab.ui.control.Button
        waveformsButton         matlab.ui.control.Button
        DepthLabel              matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        assessSpikesButton      matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        SUListBox               matlab.ui.control.ListBox
        SUListBoxLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        data_user % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            %first the user input willbe imported, as the unit information is
            %stored in there
            app.data_user.ee = ee;

            for ii = 1 : numel(app.data_user.ee.ExpMetaData.Units)
                tmpNameList{ii} = num2str(app.data_user.ee.ExpMetaData.Units(ii));
            end
            app.SUListBox.Items = tmpNameList ;
            app.Label.Text = ee.ExpID;
            app.data_user.ee = ee;
            % import the SU
            app.SUListBox.Value = '2';
            app.data_user.SU = loadSunit(sunit (app.data_user.ee, str2num(app.SUListBox.Value)));
            % show us the depth
            depth_tmp = app.data_user.SU.Depth;
            if isnan(depth_tmp)
                depth_tmp = 'nan';
            else
                depth_tmp = num2str(depth_tmp );
            end
            app.DepthLabel.Text = strcat('Depth = ' , depth_tmp );
            % update the table
            app.data_user.numberColumns = 6;
            app.UITable.Data = cell(size(app.data_user.SU.Traces.SeriesID,1),app.data_user.numberColumns);
            app.UITable.Data(:,1) = app.data_user.SU.Traces.SeriesID;
            ColProtocol=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColIntensity=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColDuration=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColRR=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColBurst=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            for kk = 1 : numel(app.data_user.SU.Traces.SeriesID)
                T = sutr(app.data_user.ee, app.data_user.SU.Traces.SeriesID(kk));
                T = loadSutr(T);
                ColProtocol(kk)= {T.Stim(1).protocol};
                if ~isempty(T.C)
                    ColIntensity{kk}= num2str(minmax([T.C.Ical]));
                else
                    ColIntensity{kk}= num2str(minmax([T.Stim.intensity]));
                end
                ColDuration{kk}= num2str(minmax([T.Stim.duration]));
                ColRR{kk}= num2str(minmax([T.Stim.repRate]));
                try
                    ColBurst{kk} =  T.Stim(1).BurstFreq2;
                catch
                    ColBurst{kk} =' ';
                end
            end

            app.UITable.Data(:,2) = ColProtocol;
            app.UITable.Data(:,3) = ColIntensity;
            app.UITable.Data(:,4) = ColDuration;
            app.UITable.Data(:,5) = ColRR;
            app.UITable.Data(:,6) = ColBurst;
        end

        % Button pushed function: assessSpikesButton
        function assessSpikesButtonPushed(app, event)
            app.data_user.T = loadRaw(app.data_user.T );
            timeVector = 1/app.data_user.T.ST(1).SamplingRate:1/app.data_user.T.ST(1).SamplingRate:app.data_user.T.ExpInfo.NumSampleAcq*1/app.data_user.T.ST(1).SamplingRate ;

            for kk = 1 : numel(app.data_user.T.R)
                figure('NumberTitle', 'off', 'Name', ['condition:' num2str(kk)]);
                count = 1;
                for ii = 1 :  size(app.data_user.T.R(kk).DataSingleTrace,1)/2
                    subplot(5,2,count)
                    hold on
                    plot(timeVector,app.data_user.T.R(kk).StimSingleTrace(ii,:)*2e1,"k")
                    plot(timeVector,app.data_user.T.R(kk).DataSingleTrace(ii,:),"b")
                    if ~isempty(app.data_user.T.ST(kk).peaksIndex{ii})
                        plot(timeVector(app.data_user.T.ST(kk).peaksIndex{ii}),app.data_user.T.R(kk).DataSingleTrace(ii,app.data_user.T.ST(kk).peaksIndex{ii}),'or')
                    end
                    hold off
                    if ~isempty(app.data_user.T.blacklist)
                        isBL = [kk,ii] == app.data_user.T.blacklist;
                        isBL = sum(isBL');
                        if sum(isBL == 2)==1
                            blTag = "BLACKLISTED";
                        else
                            blTag = [];
                        end
                    else
                        blTag = [];
                    end
                    title (strcat('rep:',num2str(ii), " ", blTag ))
                    if count == 1
                        title (strcat(app.data_user.T.SeriesID, " ", blTag ))
                    end
                    count = count + 1;

                end
                figure('NumberTitle', 'off', 'Name', ['condition:' num2str(kk)]);
                count = 1;

                for ii = size(app.data_user.T.R(kk).DataSingleTrace,1)/2+1 : 1 : size(app.data_user.T.R(kk).DataSingleTrace,1)
                    subplot(5,2,count)
                    hold on
                    plot(timeVector,app.data_user.T.R(kk).StimSingleTrace(ii,:)*2e1,"k")
                    plot(timeVector,app.data_user.T.R(kk).DataSingleTrace(ii,:),"b")
                    if ~isempty(app.data_user.T.ST(kk).peaksIndex{ii})
                        plot(timeVector(app.data_user.T.ST(kk).peaksIndex{ii}),app.data_user.T.R(kk).DataSingleTrace(ii,app.data_user.T.ST(kk).peaksIndex{ii}),'or')
                    end
                    if ~isempty(app.data_user.T.blacklist)
                        isBL = [kk,ii] == app.data_user.T.blacklist;
                        isBL = sum(isBL');
                        if sum(isBL == 2)==1
                            blTag = "BLACKLISTED";
                        else
                            blTag = [];
                        end
                    else
                        blTag = [];
                    end
                    title (strcat('rep:',num2str(ii), " ", blTag ))
                    if count == 1
                        title (strcat(app.data_user.T.SeriesID, " ", blTag ))
                    end
                    count = count + 1;
                end
            end

        end

        % Value changed function: SUListBox
        function SUListBoxValueChanged(app, event)
            value = app.SUListBox.Value;
            app.data_user.SU = loadSunit(sunit (app.data_user.ee, str2num(app.SUListBox.Value)));

            depth_tmp = app.data_user.SU.Depth;
            if isnan(depth_tmp)
                depth_tmp = 'nan';
            else
                depth_tmp = num2str(depth_tmp );
            end
            app.DepthLabel.Text = strcat('Depth = ' , depth_tmp );

            app.UITable.Data = cell(size(app.data_user.SU.Traces.SeriesID,1),app.data_user.numberColumns);

            app.UITable.Data(:,1) = app.data_user.SU.Traces.SeriesID;
            ColProtocol=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColIntensity=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColDuration=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColRR=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            ColBurst=cell(numel(app.data_user.SU.Traces.SeriesID),1);
            for kk = 1 : numel(app.data_user.SU.Traces.SeriesID)
                T = sutr(app.data_user.ee, app.data_user.SU.Traces.SeriesID(kk));
                try
                    T = loadSutr(T);
                    ColProtocol(kk)= {T.Stim(1).protocol};
                    if ~isempty(T.C)
                        ColIntensity{kk}= num2str(minmax([T.C.Ical]));
                    else
                        ColIntensity{kk}= num2str(minmax([T.Stim.intensity]));
                    end
                    ColDuration{kk}= num2str(minmax([T.Stim.duration]));
                    ColRR{kk}= num2str(minmax([T.Stim.repRate]));
                    try
                        ColBurst{kk}=  T.Stim(1).BurstFreq2;
                    catch
                        ColBurst{kk} ={' '};
                    end
                catch
                    ColProtocol(kk)={' '};
                    ColIntensity{kk}= {' '};
                    ColDuration{kk}= {' '};
                    ColRR{kk}={' '};
                    ColBurst{kk} ={' '};
                end
            end
            app.UITable.Data(:,2) = ColProtocol;
            app.UITable.Data(:,3) = ColIntensity;
            app.UITable.Data(:,4) = ColDuration;
            app.UITable.Data(:,5) = ColRR;
            app.UITable.Data(:,6) = ColBurst;

        end

        % Button pushed function: waveformsButton
        function waveformsButtonPushed(app, event)
            wv_timeVector = (1/app.data_user.T.ST(1).SamplingRate:1/app.data_user.T.ST(1).SamplingRate:176*1/app.data_user.T.ST(1).SamplingRate )  -176/2*1/app.data_user.T.ST(1).SamplingRate;
            for kk = 1 :numel(app.data_user.T.WF)
                figure()
                for ii = 1 :size(app.data_user.T.WF(kk).waveform,2)
                    hold all
                    for jj = 1 : size(app.data_user.T.WF(kk).waveform{1,ii})
                        plot( wv_timeVector,app.data_user.T.WF(kk).waveform{1,ii}(jj,:))
                        xlabel('Time (s)')
                        ylabel('Amplitude (V)')
                        ttlStr = strcat("spike waveform ",app.data_user.T.SeriesID, ", StCondition: ",num2str(kk))  ;
                        title(ttlStr)
                    end
                end
            end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            tmpSUseriesID = app.UITable.Data{app.UITable.Selection(1),app.UITable.Selection(2)}
            app.data_user.T = loadSutr(sutr (app.data_user.ee,tmpSUseriesID));
        end

        % Button pushed function: stimulusSampleButton
        function stimulusSampleButtonPushed(app, event)
            stim_timeVector = 1/app.data_user.T.ST(1).SamplingRate:1/app.data_user.T.ST(1).SamplingRate*50:1/app.data_user.T.ST(1).SamplingRate*50*numel(app.data_user.T.ST(1).StimFilt50Trace);

            for kk = 1 :numel(app.data_user.T.Stim)
                figure()
                plot(stim_timeVector,app.data_user.T.ST(kk).StimFilt50Trace)
                xlabel('Time (s)')
                ylabel('StimAmpl (V?)')
                ttlStr = strcat("filtered stimulus trace, ",app.data_user.T.SeriesID, ", StCondition: ",num2str(kk))  ;
                title(ttlStr)
            end
        end

        % Button pushed function: CLOSEALLButton
        function CLOSEALLButtonPushed(app, event)
            close all;
        end

        % Button pushed function: rasterPlotButton
        function rasterPlotButtonPushed(app, event)

            rasterPlot(app.data_user.T)

        end

        % Button pushed function: FSLButton
        function FSLButtonPushed(app, event)

            firstSpikeLatency(app.data_user.T,1)


        end

        % Button pushed function: ISIButton
        function ISIButtonPushed(app, event)
            metricsISI(app.data_user.T,1,[150 350])

        end

        % Button pushed function: VSButton
        function VSButtonPushed(app, event)
            VS = metricsVS(app.data_user.T,1,[150 350])
        end

        % Button pushed function: PSTHButton
        function PSTHButtonPushed(app, event)
            oPSTH(app.data_user.T)
        end

        % Button pushed function: SPpPButton
        function SPpPButtonPushed(app, event)
                     metricsSPP(app.data_user.T)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SUListBoxLabel = uilabel(app.UIFigure);
            app.SUListBoxLabel.Text = 'SU ';
            app.SUListBox = uilistbox(app.UIFigure);
            app.SUListBox.ValueChangedFcn = createCallbackFcn(app, @SUListBoxValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.assessSpikesButton = uibutton(app.UIFigure, 'push');
            app.assessSpikesButton.ButtonPushedFcn = createCallbackFcn(app, @assessSpikesButtonPushed, true);
            app.assessSpikesButton.Text = 'assessSpikes';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SeriesID'; 'Protocol'; 'Intensity'; 'Duration'; 'RR'; 'BurstFreq'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DepthLabel = uilabel(app.UIFigure);
            app.DepthLabel.Text = 'Depth: ';
            app.waveformsButton = uibutton(app.UIFigure, 'push');
            app.waveformsButton.ButtonPushedFcn = createCallbackFcn(app, @waveformsButtonPushed, true);
            app.waveformsButton.Text = 'waveforms';
            app.stimulusSampleButton = uibutton(app.UIFigure, 'push');
            app.stimulusSampleButton.ButtonPushedFcn = createCallbackFcn(app, @stimulusSampleButtonPushed, true);
            app.stimulusSampleButton.Text = 'stimulusSample';
            app.rasterPlotButton = uibutton(app.UIFigure, 'push');
            app.rasterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @rasterPlotButtonPushed, true);
            app.rasterPlotButton.Text = 'rasterPlot';
            app.CLOSEALLButton = uibutton(app.UIFigure, 'push');
            app.CLOSEALLButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEALLButtonPushed, true);
            app.CLOSEALLButton.Text = 'CLOSE ALL';
            app.VSButton = uibutton(app.UIFigure, 'push');
            app.VSButton.ButtonPushedFcn = createCallbackFcn(app, @VSButtonPushed, true);
            app.VSButton.Text = 'VS';
            app.EntrainmentIndexButton = uibutton(app.UIFigure, 'push');
            app.EntrainmentIndexButton.Text = 'Entrainment Index';
            app.ISIButton = uibutton(app.UIFigure, 'push');
            app.ISIButton.ButtonPushedFcn = createCallbackFcn(app, @ISIButtonPushed, true);
            app.ISIButton.Text = 'ISI';
            app.CVButton = uibutton(app.UIFigure, 'push');
            app.CVButton.Text = 'CV';
            app.FSLButton = uibutton(app.UIFigure, 'push');
            app.FSLButton.ButtonPushedFcn = createCallbackFcn(app, @FSLButtonPushed, true);
            app.FSLButton.Text = 'FSL';
            app.PSTHButton = uibutton(app.UIFigure, 'push');
            app.PSTHButton.ButtonPushedFcn = createCallbackFcn(app, @PSTHButtonPushed, true);
            app.PSTHButton.Text = 'PSTH';
            app.SPpPButton = uibutton(app.UIFigure, 'push');
            app.SPpPButton.ButtonPushedFcn = createCallbackFcn(app, @SPpPButtonPushed, true);
            app.SPpPButton.Text = 'SPpP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreSU(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end