classdef oCIimageCalib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        matdirListBox                   matlab.ui.control.ListBox
        matdirListBoxLabel              matlab.ui.control.Label
        photoBackgroundEditField        matlab.ui.control.NumericEditField
        photoBackgroundEditFieldLabel   matlab.ui.control.Label
        nEmitEditField                  matlab.ui.control.EditField
        nEmitEditFieldLabel             matlab.ui.control.Label
        wavelengthButtonGroup           matlab.ui.container.ButtonGroup
        Button527                       matlab.ui.control.RadioButton
        Button488                       matlab.ui.control.RadioButton
        emittToggleStateEditField       matlab.ui.control.EditField
        emittToggleStateEditFieldLabel  matlab.ui.control.Label
        upperEditField                  matlab.ui.control.EditField
        upperEditFieldLabel             matlab.ui.control.Label
        emitterListBox                  matlab.ui.control.ListBox
        emitterListBoxLabel             matlab.ui.control.Label
        importDirEditField              matlab.ui.control.EditField
        importDirEditFieldLabel         matlab.ui.control.Label
        photoOffsetEditField            matlab.ui.control.NumericEditField
        photoOffsetEditFieldLabel       matlab.ui.control.Label
        photoPerStimEditField           matlab.ui.control.NumericEditField
        photoPerStimEditFieldLabel      matlab.ui.control.Label
        lowerEditField                  matlab.ui.control.EditField
        lowerEditFieldLabel             matlab.ui.control.Label
        circuitRLButtonGroup            matlab.ui.container.ButtonGroup
        x7Button                        matlab.ui.control.RadioButton
        x4Button                        matlab.ui.control.RadioButton
        x10Button                       matlab.ui.control.RadioButton
        emitter_individualEditField     matlab.ui.control.NumericEditField
        emitter_individualEditFieldLabel  matlab.ui.control.Label
        showDetectButton                matlab.ui.control.StateButton
        showEmittButton                 matlab.ui.control.StateButton
        closeallButton                  matlab.ui.control.Button
        showROIButton                   matlab.ui.control.StateButton
        refreshButton                   matlab.ui.control.Button
        imageListBox                    matlab.ui.control.ListBox
        imageListBoxLabel               matlab.ui.control.Label
        commandListBox                  matlab.ui.control.ListBox
        commandListBoxLabel             matlab.ui.control.Label
        saveButton_2                    matlab.ui.control.Button
        ActionsLabel                    matlab.ui.control.Label
        detectButton                    matlab.ui.control.Button
        circuitryButton                 matlab.ui.control.Button
        checkButton                     matlab.ui.control.Button
        directoryEditField              matlab.ui.control.EditField
        directoryEditFieldLabel         matlab.ui.control.Label
        emitterButton                   matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        userEditField                   matlab.ui.control.EditField
        userEditFieldLabel              matlab.ui.control.Label
        oCI_typeEditField               matlab.ui.control.EditField
        oCI_typeEditFieldLabel          matlab.ui.control.Label
        oCI_IDEditField                 matlab.ui.control.EditField
        oCI_IDEditFieldLabel            matlab.ui.control.Label
        imgAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        oci % the structure containing all the oCI information
        ui % user input, for the ROI, the emitter position, the intensity threshold
        allphoto
        tmp
    end

    methods (Access = private)

        function updateUserInput(app)
            % pulls the updated user input values from the app straucture and updates
            % the oci structure (oci : optical cochlear implant, filed of app)
            app.oci.ociID = app.oCI_IDEditField.Value ;
            app.oci.oCI_type = app.oCI_typeEditField.Value ;
            app.oci.userID = app.userEditField.Value ;

            switch app.circuitRLButtonGroup.SelectedObject.Text
                case '1x10'
                    app.oci.nemitters = 10 ;
                    app.oci.line = 10 ;
                    app.oci.row = 1 ;
                    app.oci.totalStim = app.oci.line*app.oci.row * app.photoPerStimEditField.Value;
                case '4x4'
                    app.oci.nemitters = 16 ;
                    app.oci.line = 4 ;
                    app.oci.row = 4 ;
                    app.oci.totalStim = app.oci.line*app.oci.row * app.photoPerStimEditField.Value;
                case '7x7'
                    app.oci.nemitters = 49 ;
                    app.oci.line = 7 ;
                    app.oci.row = 7 ;
                    app.oci.totalStim = app.oci.line*app.oci.row * app.photoPerStimEditField.Value;
            end

            app.emitterListBox.Items = cellstr(num2str((1:app.oci.nemitters)', '%d'));

            if ~isnan(str2double(app.nEmitEditField.Value))
                app.oci.nemitters = str2double(app.nEmitEditField.Value) ;
            end

        end

        function [croppedImage,croppedImageIndex] = cropROI(~,image, centerX, centerY, radius)
            % Get the size of the image
            [rows, cols, ~] = size(image);

            % Initialize the cropped image
            croppedImage = uint8(zeros(2 * radius + 1, 2 * radius + 1, 3));

            % Define the limits for the circular ROI
            x_start = max(centerX - radius, 1);
            x_end = min(centerX + radius, cols);
            y_start = max(centerY - radius, 1);
            y_end = min(centerY + radius, rows);
            % Apply the  mask to each color channel
            for c = 1:3
                croppedImage(:, :, c) = image(y_start:y_end, x_start:x_end, c);
            end
            croppedImageIndex.x_start = x_start;
            croppedImageIndex.y_start = y_start;
            croppedImageIndex.x_end = x_end;
            croppedImageIndex.y_end = y_end;
        end

        function clDistance = distRGB(~,image, rgbTriplet)
            R = double(image(:,:,1));
            G = double(image(:,:,2));
            B = double(image(:,:,3));

            % Calculate distances in each channel
            %            distance = sqrt((mean(mean(rgbTriplet(1))) - mean(mean(R))).^2 + (rgbTriplet(2) - mean(mean(G))).^2 + (rgbTriplet(3) - mean(mean(B))).^2);
            distance = sqrt((rgbTriplet(3) - mean(mean(B))).^2);
            %  'la'
            clDistance =distance;
        end

        function results = distBaseline(~,image)
            % calculate the distance form a non stimulated region, a non
            % activated emitter.

            R = double(image(:,:,1));
            G = double(image(:,:,2));
            B = double(image(:,:,3));

            % Calculate distances in each channel
            distance = sqrt((rgbTriplet(1) - R).^2 + (rgbTriplet(2) - G).^2 + (rgbTriplet(3) - B).^2);

            distance = uint8(mean(distance, 3));
            clDistance = mean(mean(distance));

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set default values

            app.oCI_IDEditField.Value ="x1_37";%"GreenCree2cable1";
            app.oCI_typeEditField.Value = "creeLED";
            app.userEditField.Value = "AV";

            app.directoryEditField.Value = "D:\vavakou\creeCalibAV\x\x1_37";

            updateUserInput(app)

        end

        % Value changed function: oCI_typeEditField
        function rowLine(app, event)
            %             if strcmp(app.oCI_typeEditField.Value,"creeLED") == 1
            %                 app.lineEditField.Value = "10";
            %                 app.rowEditField.Value = "1";
            %             end
            updateUserInput(app)

        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            % in a separate window, the first image is shown and the ser can choose the region of interest for further image processing
            figure()
            imagePath = fullfile(app.directoryEditField.Value, app.imageListBox.Value);
            % Load the image
            subplot(2,1,1)
            imageSource = imread(imagePath);
            imshow(imageSource);
            out = drawrectangle();
            subplot(2,1,2)
            roiPosition = out.Position;

            % Extract the ROI from the image
            roi = imcrop(imageSource, roiPosition);
            imshow(roi);
            %store roi and cropped photos

            for ii = 1 : app.allphoto.nPhotos
                app.allphoto.roi(ii).coordinates  = roiPosition;
            end

            for ii = 1 : app.allphoto.nPhotos

                imagePath = fullfile(app.directoryEditField.Value, app.ui.imageListBox.Items{ii});
                % Load the image
                imageSource = imread(imagePath);
                app.allphoto.roi(ii).roiImSrc = imcrop(imageSource,     app.allphoto.roi(ii).coordinates);
            end

        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)

            updateUserInput(app);
            D = dir(app.directoryEditField.Value);
            [~,dateIndex] = sort([D.datenum]); %sortTIme
            D = D(dateIndex);
            baslerInd = 1 : numel(D);
            for jj = 1 : numel(D)    %is Basler image?
                if ~contains( D(jj).name, "Basler")
                    baslerInd(jj) = nan;
                end
            end
            D = D(denan(baslerInd));
            app.imageListBox.Items = {D.name};
            app.ui.imageListBox.Items =  {D.name};
            app.ui.commandIndex = 1:numel(app.ui.imageListBox.Items) ;


            imagePath = fullfile(app.directoryEditField.Value, app.imageListBox.Value);
            % Load the image
            %gca(app.imgAxes.Parent)
            imsrsc =  imread(imagePath);
            imshow (imsrsc,'Parent',app.imgAxes)
            app.allphoto.nPhotos = numel(D);


            % repeat the process for the .mat files.
            D = dir(app.directoryEditField.Value);
            [~,dateIndex] = sort([D.datenum]); %sortTIme
            D = D(dateIndex);
            matInd =1 : numel(D);
            for jj = 1 : numel(D)    %is mat file?
                if ~contains( D(jj).name, ".mat")
                    matInd(jj) = nan;
                end
            end

            % update the mat direcotry - the mat file, output of the optocidevboard
            % should be in the folder where the images are sourced from. the listbox
            % will present all the mat file so the user can choose.In future release,
            % the this should be compatible with the stimboard and the automated
            % calibration tool

            Dmat = D(denan(matInd));
            app.matdirListBox.Items = {Dmat.name};


        end

        % Value changed function: imageListBox
        function imageListBoxValueChanged(app, event)
            selectedPhotoIndex = find(strcmp(app.imageListBox.Items, event.Value));
            app.ui.selectedPhotoIndex = app.ui.commandIndex(selectedPhotoIndex);
            selectedPhotoIndex =  app.ui.selectedPhotoIndex;
            switch  app.showROIButton.Value
                case 1
                    imshow (app.allphoto.roi(selectedPhotoIndex).roiImSrc,'Parent',app.imgAxes);
                case 0
                    imagePath = fullfile(app.directoryEditField.Value, app.imageListBox.Value);
                    % Load the image
                    imsrc=  imread(imagePath);
                    imshow (imsrc,'Parent',app.imgAxes)
            end
            switch app.showEmittButton.Value
                case 1
                    clmap = cool(app.oci.nemitters);
                    hold(app.imgAxes,"on");
                    for ii = 1 : app.oci.nemitters
                        %  plot(app.imgAxes, app.ui.emmittersROI(ii,1),app.ui.emmittersROI(ii,2),'+','color',clmap(ii,1:3));
                        text(app.imgAxes, app.ui.emmittersROI(ii,1),app.ui.emmittersROI(ii,2)+20,num2str(ii),'color',clmap(ii,1:3))
                    end
            end
            switch app.showDetectButton.Value
                case 1
                    hold(app.imgAxes,"on");
                    for ii = 1 : app.oci.nemitters
                        if app.ui.pxemit(selectedPhotoIndex).emitter(ii).activated == 1
                            plot(app.imgAxes, app.ui.emmittersROI(ii,1),app.ui.emmittersROI(ii,2),'o','color','r','linewidth',0.5);
                        end
                    end
            end

        end

        % Value changed function: showROIButton
        function showROIButtonValueChanged(app, event)
            value = app.showROIButton.Value;

        end

        % Button pushed function: emitterButton
        function emitterButtonPushed(app, event)
            f_em = figure('Position',[245 479 995 619]);
            % text(gcf,-104,9,num2str(app.oci.nemitters))
            app.oci.cropRadius = 3;

            subplot(3,1,1)
            imshow(app.allphoto.roi(app.ui.selectedPhotoIndex).roiImSrc);

            G = ginput(app.oci.nemitters);
            app.ui.emmittersROI = G;

            subplot(3,1,2)
            imshow(app.allphoto.roi(app.ui.selectedPhotoIndex).roiImSrc);
            hold on
            plot(G(:,1),G(:,2),'r+')

            subplot(3,1,3)
            %  imshow(app.allphoto.roi(app.ui.selectedPhotoIndex).roiImSrc);

            pxemit = struct( 'emitter',[]);
            pxemit(app.allphoto.nPhotos).emitter =[];
            emitter = struct('raw',[],'cldistR',[],'cldistN',[],'activated',[]) ;
            emitter(app.oci.nemitters).raw = [];
            %ui hardwired parameters, like pixel region on interest and the color
            %target
            % latetr is also the threshold

            %
            %the first two images shoud be with no current, so I can use them for setiting the baseline.
            for ii = app.ui.selectedPhotoIndex
                tmp_img = app.allphoto.roi(ii).roiImSrc;
                for jj = 1 : app.oci.nemitters
                    [pxemit(ii).emitter(jj).raw, imgInd(jj)] =  cropROI(app,app.allphoto.roi(ii).roiImSrc, app.ui.emmittersROI(jj,1),app.ui.emmittersROI(jj,2),app.oci.cropRadius);
                    tmp_img(imgInd(jj).y_start:imgInd(jj).y_end,imgInd(jj).x_start:imgInd(jj).x_end,: )=0;%pxemit(ii).emitter(jj).raw;
                end
            end
            imshow(tmp_img);
            hold on
            for kk= 1: app.oci.nemitters
                text(gca, app.ui.emmittersROI(kk,1),app.ui.emmittersROI(kk,2)+20,num2str(kk),'color',[0 0 0 ])

            end

            %%


        end

        % Callback function
        function ImageClicked(app, event)

        end

        % Button pushed function: detectButton
        function detectButtonPushed(app, event)

            %collect all the intensities of
            %figure()
            %collect all the pixel data of relevance
            emitter = struct('raw',[],'cldistR',[],'cldistN',[],'activated',[]) ;
            emitter(app.oci.nemitters).raw = [];
            %ui hardwired parameters, like pixel region on interest and the color
            %target
            % latetr is also the threshold
            pxemit = app.ui.pxemit;
            %
            hueThreshold = 1.1; % Adjust as needed
            hueAll = struct();

            for jj = 1 : app.oci.nemitters
                hueRatioA=[];

                for ii = 1:app.allphoto.nPhotos%%app.ui.commandIndex%1:length(pxemit)

                    hsvBackground = (pxemit(app.photoBackgroundEditField.Value).emitter(jj).raw);%rgb2lab
                    % Extract background color (e.g., average hue value)
                    switch str2double(app.wavelengthButtonGroup.Children(find([app.wavelengthButtonGroup.Children.Value]==1)).Text)
                        case 488
                            backgroundHue = mean(hsvBackground(:,:,2),'all');
                            % Convert ROI to HSV color space
                            hsvROI = (pxemit(ii).emitter(jj).raw);%rgb2lab
                            hueChannel = mean(hsvROI(:,:,2),'all');
                            %
                        case 527
                            backgroundHue = mean(hsvBackground(:,:,2),'all');
                            % Convert ROI to HSV color space
                            hsvROI = (pxemit(ii).emitter(jj).raw);%rgb2lab
                            hueChannel = mean(hsvROI(:,:,2),'all');
                            %
                    end
                    % pxemit(ii).emitter(jj).hueChannel=hueChannel;
                    % Categorize based on threshold
                    hueRatio=hueChannel/backgroundHue;
                    if ismember (ii,app.ui.imageListBox.Index3_50)
                        hueRatioA = [hueRatioA,hueRatio];
                    end
                    if hueRatio > str2double(app.upperEditField.Value )  || hueRatio < str2double(app.lowerEditField.Value )  %metricsDist.R(jj).ref - 10*metricsDist.R(jj).std pxemit(ii).emitter(jj).cldistR <=metricsDist.R(jj).ref - 10*metricsDist.R(jj).std %str2num(app.lowerEditField.Value)
                        pxemit(ii).emitter(jj).activated = 1;
                    else
                        pxemit(ii).emitter(jj).activated = 0;
                    end
                    plot(jj*ones(numel(hueRatioA),1,1),hueRatioA,'*k')
                    hold on
                end
            end

            plot([1,numel(hueRatioA)],[str2num(app.upperEditField.Value )  str2num(app.upperEditField.Value ) ] )
            plot([1,numel(hueRatioA)],[str2num(app.lowerEditField.Value )  str2num(app.lowerEditField.Value ) ] )
            title("hue ratios for 3_50 command")
            xlabel("LED on")
            ylabel("hue ratio relative to baseline in 2nd channel")


            app.ui.pxemit=pxemit;
        end

        % Value changed function: showDetectButton
        function showDetectButtonValueChanged(app, event)


        end

        % Button pushed function: circuitryButton
        function circuitryButtonPushed(app, event)
            activeEmitter = [];
            %
            inMATdir = fullfile(app.directoryEditField.Value,app.matdirListBox.Value);

            inMAT = load(inMATdir);
            %is uLED?
            switch app.oCI_typeEditField.Value
                case "uLED"
                    allCommandsLR = uLEDBin2N (inMAT.LEDon);
                    % find the calibration command
                    switch app.circuitRLButtonGroup.SelectedObject.Text
                        case '4x4'
                            tmpIn = uLEDBin2N(inMAT.LEDon);
                            LEDon = uLEDParser4x4RL2Emit(tmpIn(:,2),tmpIn(:,1));
                        case '7x7'
                            tmpIn = uLEDBin2N(inMAT.LEDon);
                            LEDon = uLEDParser7x7RL2Emit(tmpIn(:,2),tmpIn(:,1));
                    end
                    % figure

                    clmap = jet(size(allCommandsLR,1));%app.oci.nemitters);
                    plotCircuit = [];
                    for ii = 1 : size(allCommandsLR,1)
                        plotCircuit(ii).Line = allCommandsLR(ii,1);
                        plotCircuit(ii).Row = allCommandsLR(ii,2);
                        tmpEmitterStatus = [app.ui.pxemit(app.ui.commandIndex(ii)).emitter.activated];
                        plotCircuit(ii).ActiveEmitters = find(tmpEmitterStatus ==1);
                        plotCircuit(ii).tmpMrk = '-';
                        plotCircuit(ii).LEDon = LEDon(ii);
                        if numel(plotCircuit(ii).ActiveEmitters)>1
                            plotCircuit(ii).tmpMrk = '-.';
                        end
                    end

                    crct =  figure();
                    %plot
                    subplot(4,1,1)

                    hold all
                    for ii = 1 : numel(plotCircuit)
                        plot(plotCircuit(ii).LEDon,4,'Marker','o','color',clmap(ii,:) , 'LineWidth',2)
                        plot(plotCircuit(ii).LEDon,0,'Marker','sq','MarkerEdgeColor',clmap(ii,:) , 'MarkerFaceColor',clmap(ii,:) ,'LineWidth',2)

                        for jj = 1 : numel(plotCircuit(ii).ActiveEmitters)
                            hold on
                            plot([plotCircuit(ii).LEDon, plotCircuit(ii).ActiveEmitters(jj)],[4,0],'linestyle',plotCircuit(ii).tmpMrk,'color',clmap(ii,:))
                        end
                    end
                    text( -0.5,4,"LEDon")
                    text( -0.5,0,"LED active")

                    yticks([])

                    subplot(4,1,2)
                    hold all
                    plot([unique([plotCircuit(:).Line ])],ones(numel(unique([plotCircuit(:).Line])),1,1)*4,'ko', 'linewidth',2)
                    text( -0.5,4,"line")
                    plot([unique([plotCircuit(:).Row])],ones(numel(unique([plotCircuit(:).Row])),1,1)*2,'bo', 'linewidth',2)
                    text(-0.5,2,"row","color",'b')
                    addJitter = 0.15;
                    for ii = 1 : numel(plotCircuit)
                        plot(plotCircuit(ii).LEDon,0,'Marker','sq','MarkerEdgeColor',clmap(ii,:) , 'MarkerFaceColor',clmap(ii,:) ,'LineWidth',2)

                        for jj = 1 : numel(plotCircuit(ii).ActiveEmitters)
                            plot([plotCircuit(ii).Line, plotCircuit(ii).Row, plotCircuit(ii).ActiveEmitters(jj)],[4,2,0],'linestyle',plotCircuit(ii).tmpMrk,'color',clmap(ii,:))
                        end
                    end
                    %        xlim([-1,app.oci.nemitters+1])
                    yticks([])

                    tmpXlim =xlim();
                    %% here I will run parts of the calibration visualization of GH
                    ExpInfo = inMAT.ExpInfo;
                    calFactor=2.78;

                    calFactor=2.78;
                    ExpInfo.NI.FreqAcq=100000;
                    ExpInfo.NI.timeView=0.02;
                    ExpInfo.NI.NumSampleAcq=ExpInfo.NI.FreqAcq*ExpInfo.NI.timeView;
                    ExpInfo.Data.xAcq=1:ExpInfo.NI.NumSampleAcq;ExpInfo.Data.xAcq=ExpInfo.Data.xAcq/ExpInfo.NI.FreqAcq;
                    nrLEDcal=0;

                    for iLED=1:size(inMAT.LEDcalUlbricht,1) for iIntens=1:size(inMAT.LEDcalUlbricht,2) LEDcalAll(iLED,iIntens)  = inMAT.LEDcalUlbricht(iLED,iIntens); end; end
                    subplot(4,1,3)
                    title(['Kalibrierung Umax=10V Eichfaktor: ' num2str(calFactor) ' InterStim: ' num2str(ExpInfo.NI.StimRepeatPerSec) ' Hz'] ); xlabel(['AU']); ylabel(['mW']);
                    plot(LEDcalAll(:,:) ,'*');
                    ylabel('mW')
                    xlabel('LED')
                    xlim (  tmpXlim);
                    subplot(4,1,4)
                    % plot(inMAT.LEDcalUlbricht(:,:),'*')
                    for iIntens=1:size(inMAT.LEDIntensityDevBoard,2)-1
                        x(iIntens)=inMAT.LEDIntensityDevBoard(iIntens);
                    end
                    hold on;
                    for iLed=1 : numel(LEDon)

                        for iIntens=1:size(inMAT.LEDIntensityDevBoard,2)-1  y(iIntens)=LEDcalAll(LEDon(iLed),iIntens); end
                        %                         if iLed== 12 || iLed==13 || iLed==14 || iLed==28 || iLed==29 || iLed==42

                        plot(x,y,'-'); plot(x,y,'*');
                        text(128,y(end),num2str(iLed),'FontSize',7)
                        ylabel('mW')
                        xlabel('AU')

                    end; hold off

                    app.tmp.crct=crct;

                case "creeLED"
                    allCommandsLR = uLEDBin2N (inMAT.LEDon);
                    switch app.circuitRLButtonGroup.SelectedObject.Text
                        case '1x10'
                            tmpIn = uLEDBin2N(inMAT.LEDon);
                            LEDon = creeLEDParser10x1RL2Emit(tmpIn(:,2),tmpIn(:,1));
                    end
                    crct =  figure();


                    clmap = jet(size(allCommandsLR,1));%app.oci.nemitters);
                    plotCircuit = [];
                    for ii = 1 : size(allCommandsLR,1)
                        plotCircuit(ii).Line = allCommandsLR(ii,1);
                        plotCircuit(ii).Row = allCommandsLR(ii,2);
                        tmpEmitterStatus = [app.ui.pxemit(app.ui.commandIndex(ii)).emitter.activated];
                        plotCircuit(ii).ActiveEmitters = find(tmpEmitterStatus ==1);
                        plotCircuit(ii).tmpMrk = '-';
                        plotCircuit(ii).LEDon = LEDon(ii);
                        if numel(plotCircuit(ii).ActiveEmitters)>1
                            plotCircuit(ii).tmpMrk = '-.';
                        end
                    end

                    %plot
                    subplot(3,1,1)

                    hold all
                    for ii = 1 : numel(plotCircuit)
                        plot(plotCircuit(ii).LEDon,4,'Marker','o','color',clmap(ii,:) , 'LineWidth',2)
                        plot(plotCircuit(ii).LEDon,0,'Marker','sq','MarkerEdgeColor',clmap(ii,:) , 'MarkerFaceColor',clmap(ii,:) ,'LineWidth',2)

                        for jj = 1 : numel(plotCircuit(ii).ActiveEmitters)
                            hold on
                            plot([plotCircuit(ii).LEDon, plotCircuit(ii).ActiveEmitters(jj)],[4,0],'linestyle',plotCircuit(ii).tmpMrk,'color',clmap(ii,:))
                        end
                    end
                    text( -0.5,4,"LEDon")
                    text( -0.5,0,"LED active")

                    yticks([])

                    tmpXlim =xlim();

                    %% here I will run parts of the calibration visualization of GH
                    ExpInfo = inMAT.ExpInfo;
                    calFactor=2.78;

                    calFactor=2.78;
                    ExpInfo.NI.FreqAcq=100000;
                    ExpInfo.NI.timeView=0.02;
                    ExpInfo.NI.NumSampleAcq=ExpInfo.NI.FreqAcq*ExpInfo.NI.timeView;
                    ExpInfo.Data.xAcq=1:ExpInfo.NI.NumSampleAcq;ExpInfo.Data.xAcq=ExpInfo.Data.xAcq/ExpInfo.NI.FreqAcq;
                    nrLEDcal=0;

                    for iLED=1:size(inMAT.LEDcalUlbricht,1) for iIntens=1:size(inMAT.LEDcalUlbricht,2) LEDcalAll(iLED,iIntens)  = inMAT.LEDcalUlbricht(iLED,iIntens); end; end
                    subplot(3,1,2)
                    title(['Kalibrierung Umax=10V Eichfaktor: ' num2str(calFactor) ' InterStim: ' num2str(ExpInfo.NI.StimRepeatPerSec) ' Hz'] ); xlabel(['AU']); ylabel(['mW']);
                    plot(LEDcalAll(:,:) ,'*');
                    ylabel('mW')
                    xlabel('LED')
                    xlim (  tmpXlim);
                    subplot(3,1,3)
                    % plot(inMAT.LEDcalUlbricht(:,:),'*')
                    for iIntens=1:size(inMAT.LEDIntensityDevBoard,2)-1
                        x(iIntens)=inMAT.LEDIntensityDevBoard(iIntens);
                    end
                    hold on;
                    for iLed=1 : numel(LEDon)

                        for iIntens=1:size(inMAT.LEDIntensityDevBoard,2)-1  y(iIntens)=LEDcalAll(LEDon(iLed),iIntens); end
                        %                         if iLed== 12 || iLed==13 || iLed==14 || iLed==28 || iLed==29 || iLed==42

                        plot(x,y,'-'); plot(x,y,'*');
                        text(128,y(end),num2str(iLed),'FontSize',7)
                        ylabel('mW')
                        xlabel('AU')

                    end; hold off

                    app.tmp.crct=crct;

            end


        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            %  out.app = app
            out.oci = app.oci;
            out.allphoto = app.allphoto;
            out.ui = app.ui;
            savename= strcat(out.oci.ociID);
            save(savename,"out")
            saveas(app.tmp.crct, strcat("circuit_", app.oci.ociID,"_command",app.commandListBox.Value,".fig"));
            saveas(app.tmp.crct, strcat("circuit_", app.oci.ociID,"_command",app.commandListBox.Value,".png"));

        end

        % Button pushed function: closeallButton
        function closeallButtonPushed(app, event)
            close all
        end

        % Value changed function: commandListBox
        function commandListBoxValueChanged(app, event)
            value = app.commandListBox.Value;
            switch value
                case 'all'
                    app.imageListBox.Items = app.ui.imageListBox.Items;
                    app.ui.commandIndex =  1:numel(app.ui.imageListBox.Items );
                case '1_0'
                    app.imageListBox.Items = app.ui.imageListBox.Items(app.photoOffsetEditField.Value+1:4:end);
                    app.ui.imageListBox.Index1_0 = [app.photoOffsetEditField.Value+1:4:numel(app.ui.imageListBox.Items)];
                    app.ui.commandIndex = app.ui.imageListBox.Index1_0 ;
                case '2_50'
                    app.imageListBox.Items = app.ui.imageListBox.Items(app.photoOffsetEditField.Value+2:4:end);
                    app.ui.imageListBox.Index2_50 = [app.photoOffsetEditField.Value+2:4:numel(app.ui.imageListBox.Items)];
                    app.ui.commandIndex = app.ui.imageListBox.Index2_50  ;
                case '3_50'
                    app.imageListBox.Items = app.ui.imageListBox.Items(app.photoOffsetEditField.Value+3:4:end);
                    for ii = 1:numel(app.imageListBox.Items)
                        app.imageListBox.Items{ii} = strcat(num2str(ii),'_', app.imageListBox.Items{ii});
                    end
                    app.ui.imageListBox.Index3_50 = [app.photoOffsetEditField.Value+3:4:numel(app.ui.imageListBox.Items)];
                    app.ui.commandIndex = app.ui.imageListBox.Index3_50  ;
                case '4_0'
                    app.imageListBox.Items = app.ui.imageListBox.Items(app.photoOffsetEditField.Value+4:4:end);
                    app.ui.imageListBox.Index4_0 = [app.photoOffsetEditField.Value+4:4:numel(app.ui.imageListBox.Items)];
                    app.ui.commandIndex = app.ui.imageListBox.Index4_0  ;
            end

        end

        % Value changed function: emitter_individualEditField
        function emitter_individualEditFieldValueChanged(app, event)
            value = app.emitter_individualEditField.Value;
            %

            f_em = figure('Position',[245 479 995 619]);
            % text(gcf,-104,9,num2str(app.oci.nemitters))
            subplot(3,1,2)
            %  imshow(app.allphoto.roi(app.ui.selectedPhotoIndex).roiImSrc);
            pxemit = app.ui.pxemit;
            %ui hardwired parameters, like pixel region on interest and the color
            %target
            % latetr is also the threshold

            %
            %the first two images shoud be with no current, so I can use them for setiting the baseline.
            for ii = app.ui.selectedPhotoIndex

                tmp_img = app.allphoto.roi(ii).roiImSrc;
                for jj = 1 : app.oci.nemitters
                    [pxemit(ii).emitter(jj).raw, imgInd(jj)] =  cropROI(app,app.allphoto.roi(ii).roiImSrc, app.ui.emmittersROI(jj,1),app.ui.emmittersROI(jj,2),app.oci.cropRadius);
                    tmp_img(imgInd(jj).y_start:imgInd(jj).y_end,imgInd(jj).x_start:imgInd(jj).x_end,: )=0;%pxemit(ii).emitter(jj).raw;
                end
            end
            imshow(tmp_img);
            hold on
            for kk= 1: app.oci.nemitters
                text(gca, app.ui.emmittersROI(kk,1),app.ui.emmittersROI(kk,2)+20,num2str(kk),'color',[0 0 0 ])

            end
            subplot(3,1,1)
            imshow(app.allphoto.roi(app.ui.selectedPhotoIndex).roiImSrc);
            G = ginput(1);
            hold on
            plot(G(:,1),G(:,2),'r+')
            app.ui.emmittersROI(value,:) = G;

        end

        % Callback function
        function emittertogglestateEditFieldValueChanged(app, event)
            value = app.emittertogglestateEditField.Value;

            toggleValue = str2double(strsplit(get(editField, 'String'),','));
            %   Perform action (e.g., display the value)
            %                 for kk = 1 : length(toggleValue)
            %                     tempState =   app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated;
            %                     switch tempState
            %                         case 0
            %                             app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated = 1;
            %
            %                         case 1
            %                             app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated = 0;
            %                     end
            %                 end
            %

            if value>0 && value <= 49
                tempState =   app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(value).activated;
                switch tempState
                    case 0
                        app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(value).activated =1;
                    case 1
                        app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(value).activated =0;
                end
            end

        end

        % Value changed function: importDirEditField
        function importDirEditFieldValueChanged(app, event)
            %             value = app.importDirEditField.Value;
            %(app.importDirEditField.Value = 'W:\home\avavakou\temp\daily_log\dl_19_04_2024\4_4x4.mat')

            load(app.importDirEditField.Value);
            %load('W:\home\avavakou\temp\daily_log\dl_19_04_2024\4_4x4.mat')
            app.oci = out.oci;
            app.allphoto = out.allphoto;
            app.ui = out.ui;


        end

        % Value changed function: lowerEditField
        function lowerEditFieldValueChanged(app, event)
            value = app.lowerEditField.Value;

        end

        % Value changed function: emitterListBox
        function emitterListBoxValueChanged(app, event)

            emitFig = figure()
            nSubPlots = numel(app.ui.imageListBox.Index3_50);
            set(emitFig, 'NumberTitle', 'off', 'Name', ['EMITTER:',char(app.emitterListBox.Value)]);
            switch nSubPlots
                case 49
                    for ii = 1 :  nSubPlots
                        subplot(7,7,ii)
                        imshow(app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).raw)
                        switch app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).activated
                            case 0
                                title(strcat('fr:',num2str(ii)),'color','k')
                            case 1
                                title(strcat('fr:',num2str(ii)),'color','r')
                        end
                    end

                    % button toggle state
                    'la';
                case 16
                    for ii = 1 :  nSubPlots
                        subplot(4,4,ii)
                        imshow(app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).raw)
                        switch app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).activated
                            case 0
                                title(strcat('fr:',num2str(ii)),'color','k')
                            case 1
                                title(strcat('fr:',num2str(ii)),'color','r')
                        end
                    end
                case 10
                    for ii = 1 :  nSubPlots
                        subplot(4,4,ii)
                        imshow(app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).raw)
                        switch app.ui.pxemit(app.ui.imageListBox.Index3_50(ii)).emitter(str2num(app.emitterListBox.Value)).activated
                            case 0
                                title(strcat('fr:',num2str(ii)),'color','k')
                            case 1
                                title(strcat('fr:',num2str(ii)),'color','r')
                        end
                    end
            end
            % Inside the app's code where the button and edit boxes are defined

            %     action (e.g., display the value)
            %                 for kk = 1 : length(toggleValue)
            %                     tempState =   app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated;
            %                     switch tempState
            %                         case 0
            %                             app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated = 1;
            %
            %                         case 1
            %                             app.ui.pxemit(toggleValue(kk)).emitter(str2num(app.emitterListBox.Value)).activated = 0;
            %                     end
            %                 end
            %

        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            app.oci.cropRadius = 3;
            for ii = 1:app.allphoto.nPhotos
                % text(2.5,150+5*ii,num2str(ii),'color',clmap(ii,1:3))
                for jj = 1 : app.oci.nemitters
                    pxemit(ii).emitter(jj).raw =  cropROI(app,app.allphoto.roi(ii).roiImSrc, app.ui.emmittersROI(jj,1),app.ui.emmittersROI(jj,2),app.oci.cropRadius);
                end
            end
            app.ui.pxemit = pxemit;

        end

        % Selection changed function: circuitRLButtonGroup
        function circuitRLButtonGroupSelectionChanged(app, event)
            selectedButton = app.circuitRLButtonGroup.SelectedObject;

        end

        % Value changed function: emittToggleStateEditField
        function emittToggleStateEditFieldValueChanged(app, event)
            value = app.emittToggleStateEditField.Value;

            toggleValue = str2double(strsplit(value,','));

            for kk = 1 : length(toggleValue)
                if toggleValue(kk)>0 && toggleValue(kk) <= 49
                    tempState =   app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(toggleValue(kk)).activated;

                    switch tempState
                        case 0
                            app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(toggleValue(kk)).activated =1;

                        case 1
                            app.ui.pxemit(app.ui.selectedPhotoIndex).emitter(toggleValue(kk)).activated =0;
                    end
                end

            end

        end

        % Value changed function: matdirListBox
        function matdirListBoxValueChanged(app, event)
            value = app.matdirListBox.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.imgAxes = uiaxes(app.UIFigure);
            title(app.imgAxes, 'Title')
            xlabel(app.imgAxes, 'X')
            ylabel(app.imgAxes, 'Y')
            zlabel(app.imgAxes, 'Z')
            app.oCI_IDEditFieldLabel = uilabel(app.UIFigure);
            app.oCI_IDEditFieldLabel.Text = 'oCI_ID';
            app.oCI_IDEditField = uieditfield(app.UIFigure, 'text');
            app.oCI_IDEditField.Value = 'x1_37';
            app.oCI_typeEditFieldLabel = uilabel(app.UIFigure);
            app.oCI_typeEditFieldLabel.Text = 'oCI_type';
            app.oCI_typeEditField = uieditfield(app.UIFigure, 'text');
            app.oCI_typeEditField.ValueChangedFcn = createCallbackFcn(app, @rowLine, true);
            app.userEditFieldLabel = uilabel(app.UIFigure);
            app.userEditFieldLabel.Text = 'user';
            app.userEditField = uieditfield(app.UIFigure, 'text');
            app.ROIButton = uibutton(app.UIFigure, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Text = 'ROI';
            app.emitterButton = uibutton(app.UIFigure, 'push');
            app.emitterButton.ButtonPushedFcn = createCallbackFcn(app, @emitterButtonPushed, true);
            app.emitterButton.Text = 'emitter';
            app.directoryEditFieldLabel = uilabel(app.UIFigure);
            app.directoryEditFieldLabel.Text = 'directory';
            app.directoryEditField = uieditfield(app.UIFigure, 'text');
            app.directoryEditField.Value = 'D:\vavakou\creeCalibAV\x\x1_37';
            app.checkButton = uibutton(app.UIFigure, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.circuitryButton = uibutton(app.UIFigure, 'push');
            app.circuitryButton.ButtonPushedFcn = createCallbackFcn(app, @circuitryButtonPushed, true);
            app.circuitryButton.Text = 'circuitry';
            app.detectButton = uibutton(app.UIFigure, 'push');
            app.detectButton.ButtonPushedFcn = createCallbackFcn(app, @detectButtonPushed, true);
            app.detectButton.Text = 'detect';
            app.ActionsLabel = uilabel(app.UIFigure);
            app.ActionsLabel.Text = 'Actions';
            app.saveButton_2 = uibutton(app.UIFigure, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Text = 'save';
            app.commandListBoxLabel = uilabel(app.UIFigure);
            app.commandListBoxLabel.Text = 'command';
            app.commandListBox = uilistbox(app.UIFigure);
            app.commandListBox.Items = {'all', '1_0', '2_50', '3_50', '4_0'};
            app.commandListBox.ValueChangedFcn = createCallbackFcn(app, @commandListBoxValueChanged, true);
            app.commandListBox.Value = '3_50';
            app.imageListBoxLabel = uilabel(app.UIFigure);
            app.imageListBoxLabel.Text = 'image';
            app.imageListBox = uilistbox(app.UIFigure);
            app.imageListBox.ValueChangedFcn = createCallbackFcn(app, @imageListBoxValueChanged, true);
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.showROIButton = uibutton(app.UIFigure, 'state');
            app.showROIButton.ValueChangedFcn = createCallbackFcn(app, @showROIButtonValueChanged, true);
            app.showROIButton.Text = 'showROI';
            app.closeallButton = uibutton(app.UIFigure, 'push');
            app.closeallButton.ButtonPushedFcn = createCallbackFcn(app, @closeallButtonPushed, true);
            app.closeallButton.Text = 'close all';
            app.showEmittButton = uibutton(app.UIFigure, 'state');
            app.showEmittButton.Text = 'showEmitt';
            app.showDetectButton = uibutton(app.UIFigure, 'state');
            app.showDetectButton.ValueChangedFcn = createCallbackFcn(app, @showDetectButtonValueChanged, true);
            app.showDetectButton.Text = 'showDetect';
            app.emitter_individualEditFieldLabel = uilabel(app.UIFigure);
            app.emitter_individualEditFieldLabel.Text = 'emitter_individual';
            app.emitter_individualEditField = uieditfield(app.UIFigure, 'numeric');
            app.emitter_individualEditField.ValueChangedFcn = createCallbackFcn(app, @emitter_individualEditFieldValueChanged, true);
            app.circuitRLButtonGroup = uibuttongroup(app.UIFigure);
            app.circuitRLButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @circuitRLButtonGroupSelectionChanged, true);
            app.circuitRLButtonGroup.Title = 'circuitRL';
            app.x10Button = uiradiobutton(app.circuitRLButtonGroup);
            app.x10Button.Text = '1x10';
            app.x10Button.Value = true;
            app.x4Button = uiradiobutton(app.circuitRLButtonGroup);
            app.x4Button.Text = '4x4';
            app.x7Button = uiradiobutton(app.circuitRLButtonGroup);
            app.x7Button.Text = '7x7';
            app.lowerEditFieldLabel = uilabel(app.UIFigure);
            app.lowerEditFieldLabel.Text = 'lower';
            app.lowerEditField = uieditfield(app.UIFigure, 'text');
            app.lowerEditField.ValueChangedFcn = createCallbackFcn(app, @lowerEditFieldValueChanged, true);
            app.lowerEditField.Value = '0.8';
            app.photoPerStimEditFieldLabel = uilabel(app.UIFigure);
            app.photoPerStimEditFieldLabel.Text = 'photoPerStim';
            app.photoPerStimEditField = uieditfield(app.UIFigure, 'numeric');
            app.photoPerStimEditField.Value = 4;
            app.photoOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.photoOffsetEditFieldLabel.Text = 'photoOffset';
            app.photoOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.photoOffsetEditField.Value = 1;
            app.importDirEditFieldLabel = uilabel(app.UIFigure);
            app.importDirEditFieldLabel.Text = 'importDir';
            app.importDirEditField = uieditfield(app.UIFigure, 'text');
            app.importDirEditField.ValueChangedFcn = createCallbackFcn(app, @importDirEditFieldValueChanged, true);
            app.emitterListBoxLabel = uilabel(app.UIFigure);
            app.emitterListBoxLabel.Text = 'emitter';
            app.emitterListBox = uilistbox(app.UIFigure);
            app.emitterListBox.ValueChangedFcn = createCallbackFcn(app, @emitterListBoxValueChanged, true);
            app.upperEditFieldLabel = uilabel(app.UIFigure);
            app.upperEditFieldLabel.Text = 'upper';
            app.upperEditField = uieditfield(app.UIFigure, 'text');
            app.upperEditField.Value = '1.1';
            app.emittToggleStateEditFieldLabel = uilabel(app.UIFigure);
            app.emittToggleStateEditFieldLabel.Text = 'emittToggleState';
            app.emittToggleStateEditField = uieditfield(app.UIFigure, 'text');
            app.emittToggleStateEditField.ValueChangedFcn = createCallbackFcn(app, @emittToggleStateEditFieldValueChanged, true);
            app.wavelengthButtonGroup = uibuttongroup(app.UIFigure);
            app.wavelengthButtonGroup.Title = 'wavelength';
            app.Button488 = uiradiobutton(app.wavelengthButtonGroup);
            app.Button488.Text = '488 ';
            app.Button488.Value = true;
            app.Button527 = uiradiobutton(app.wavelengthButtonGroup);
            app.Button527.Text = '527';
            app.nEmitEditFieldLabel = uilabel(app.UIFigure);
            app.nEmitEditFieldLabel.Text = 'nEmit';
            app.nEmitEditField = uieditfield(app.UIFigure, 'text');
            app.photoBackgroundEditFieldLabel = uilabel(app.UIFigure);
            app.photoBackgroundEditFieldLabel.Text = 'photoBackground';
            app.photoBackgroundEditField = uieditfield(app.UIFigure, 'numeric');
            app.photoBackgroundEditField.Value = 1;
            app.matdirListBoxLabel = uilabel(app.UIFigure);
            app.matdirListBoxLabel.Text = 'mat dir';
            app.matdirListBox = uilistbox(app.UIFigure);
            app.matdirListBox.ValueChangedFcn = createCallbackFcn(app, @matdirListBoxValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oCIimageCalib

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end