classdef uiSU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        importLNdepthButton  matlab.ui.control.Button
        HelpButton           matlab.ui.control.Button
        checkButton          matlab.ui.control.Button
        Label                matlab.ui.control.Label
        importButton         matlab.ui.control.Button
        EXPORTButton         matlab.ui.control.Button
        UITable              matlab.ui.control.Table
    end

    properties (Access = public)
        data_user % Description

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            % alalalal
            L = listSuRaw(ee);
            L.SU_SeriesID;
            app.Label.Text = ee.ExpID;
            app.data_user.ee = ee;
            app.data_user.L = L;
            app.data_user.SeriesIDList = app.data_user.L.SU_SeriesID;


            % Add data to table
            t = cell(numel( app.data_user.SeriesIDList ),9);
            for ii = 1 : numel(app.data_user.SeriesIDList)
                t{ii,1} = char(app.data_user.SeriesIDList(ii));%seriesIDS
                t{ii,2} = nan;%filterOD% to get LN depth vallues% to get LN depth vallues
                %                 tempLoad =  load( gen_dir_name(fullfile(getSUrawDir(ee),strcat(char(app.data_user.SeriesIDList(ii)),'.mat')))); % to get LN depth vallues
                %                 if isfield(tempLoad.IsData(1),'LNPos')
                %                     t{ii,3} = tempLoad.IsData(1).LNPos;
                %                 else
                %                     t{ii,3} = nan;
                %                 end
                %                 ii
            end
            app.UITable.Data = t;
        end

        % Callback function
        function dirRawEditFieldValueChanged(app, event)
            temp_dirRaw = dir(app.dirRawEditField.Value);
            temp_dirRaw=temp_dirRaw(~ismember({temp_dirRaw.name},{'.','..'}));
            list_display_string = {temp_dirRaw.name};
            app.ExpIDListBox.Items = list_display_string;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            dindices = event.Indices;
            t =   app.UITable.Data;
            for ii = 1 : size(dindices,1)
                tempInd = dindices(ii,:);% I am not sure why this is happening...  Somehow I can not use that as indexing
                valueFirst = dindices(1,:);
                t{tempInd(1),tempInd(2)}= app.UITable.Data{valueFirst(1),valueFirst(2)};
            end
            app.UITable.Data=t;

        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)

            UT.data = app.UITable.Data;
            UT.fieldNames = app.UITable.ColumnName;
            save(fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),strcat("uiSU_", app.data_user.ee.ExpID, ".mat")),'UT');

        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),strcat("uiSU_", app.data_user.ee.ExpID, ".mat"));
            load(in_dir_name);
            app.UITable.Data=UT.data ;
            app.UITable.ColumnName = UT.fieldNames;

        end

        % Button pushed function: checkButton
        function checkButtonPushed(app, event)
            'add the tests'
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            display(  "uiSU (ee) : a user interface to assign information on the raw data. The user must assign manually differnet units, depth,electrode impledence, electrode changing events, penetrations, coordinates, filter optical density, current and assesment" )
        end

        % Button pushed function: importLNdepthButton
        function importLNdepthButtonPushed(app, event)
            % import the raw data to extract the LN depth value. It is time
            % consuming, so it is optional for the user.
            t = app.UITable.Data ;
            % Add data to table
            for ii = 1 : numel(app.data_user.SeriesIDList)
                tempLoad =  load( gen_dir_name(fullfile(getSUrawDir(app.data_user.ee),strcat(char(app.data_user.SeriesIDList(ii)),'.mat')))); % to get LN depth vallues
                if isfield(tempLoad.IsData(1),'LNPos')
                    t{ii,3} = round(tempLoad.IsData(1).LNPos);
                else
                    t{ii,3} = nan;
                end
                ii
            end
            app.UITable.Data = t;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SeriesID'; 'unit'; 'depth'; 'Impedence'; 'Electrode'; 'Penetration'; 'Coordinates'; 'ftOD'; 'Assessment'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import ';
            app.Label = uilabel(app.UIFigure);
            app.checkButton = uibutton(app.UIFigure, 'push');
            app.checkButton.ButtonPushedFcn = createCallbackFcn(app, @checkButtonPushed, true);
            app.checkButton.Text = 'check';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.importLNdepthButton = uibutton(app.UIFigure, 'push');
            app.importLNdepthButton.ButtonPushedFcn = createCallbackFcn(app, @importLNdepthButtonPushed, true);
            app.importLNdepthButton.Text = 'importLN depth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiSU(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end