classdef uiSUee < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        unitFSLButton                matlab.ui.control.Button
        oABRthresholdEditField       matlab.ui.control.EditField
        oABRthresholdEditFieldLabel  matlab.ui.control.Label
        kanamycinInjEditField        matlab.ui.control.EditField
        kanamycinInjLabel            matlab.ui.control.Label
        VirusNumberEditField         matlab.ui.control.EditField
        VirusNumberEditFieldLabel    matlab.ui.control.Label
        AnesthesiaEditField          matlab.ui.control.EditField
        AnesthesiaEditFieldLabel     matlab.ui.control.Label
        InjMethodEditField           matlab.ui.control.EditField
        InjMethodEditFieldLabel      matlab.ui.control.Label
        StrainEditField              matlab.ui.control.EditField
        StrainEditFieldLabel         matlab.ui.control.Label
        ExperimenterEditField        matlab.ui.control.EditField
        ExperimenterEditFieldLabel   matlab.ui.control.Label
        DoBEditField                 matlab.ui.control.EditField
        DoBEditFieldLabel            matlab.ui.control.Label
        ticklabIDEditField           matlab.ui.control.EditField
        ticklabIDEditFieldLabel      matlab.ui.control.Label
        GenotypeEditField            matlab.ui.control.EditField
        GenotypeEditFieldLabel       matlab.ui.control.Label
        InjDateEditField             matlab.ui.control.EditField
        InjDateEditFieldLabel        matlab.ui.control.Label
        VirusFullNameEditField       matlab.ui.control.EditField
        VirusFullNameEditFieldLabel  matlab.ui.control.Label
        OpsinEditField               matlab.ui.control.EditField
        OpsinEditFieldLabel          matlab.ui.control.Label
        SexEditField                 matlab.ui.control.EditField
        SexEditFieldLabel            matlab.ui.control.Label
        WeightEditField              matlab.ui.control.EditField
        WeightEditFieldLabel         matlab.ui.control.Label
        expDateLabel                 matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.EditField
        AgeEditFieldLabel            matlab.ui.control.Label
        SpeciesEditField             matlab.ui.control.EditField
        SpeciesEditFieldLabel        matlab.ui.control.Label
        HelpButton                   matlab.ui.control.Button
        expNameLabel                 matlab.ui.control.Label
        importButton                 matlab.ui.control.Button
        EXPORTButton                 matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    properties (Access = public)
        data_user % Description

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)
            % lalalalal
            L = listSuRaw(ee);
            L.SU_SeriesID;
            app.expNameLabel.Text = ee.ExpID;
            app.expDateLabel.Text = datestr(L.dateID_SU,1); %why it was 24?

            app.data_user.ee = ee;
            app.data_user.L = L;
            app.data_user.SeriesIDList = app.data_user.L.SU_SeriesID;

            app.ExperimenterEditField.Value = app.data_user.ee.ExperimenterID;
            %defaults
            app.SpeciesEditField.Value ="mouse";
            app.OpsinEditField.Value ="ChREEF";
            app.VirusNumberEditField.Value ="170";
            app.VirusFullNameEditField.Value ="AAV2/9_hSyn-ChRmine(T218L,S220A)-TS-EYFP-ES_WPRE_SV40poly A (#221)";

            app.GenotypeEditField.Value ="WT";
            app.StrainEditField.Value ="C57BL6/J#00245";

            app.AnesthesiaEditField.Value ='UXB';
            app.InjMethodEditField.Value ='RW';

            tmpExpID = char(app.data_user.ee.ExpID);
            app.ticklabIDEditField.Value =string(tmpExpID(4:end));


            % retrieve the unit number and depth
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID, app.data_user.ee.ExpID),strcat("uiSU_",app.data_user.ee.ExpID, ".mat"));
            UTtemp = load(in_dir_name);
            UT = UTtemp.UT; clear UTtemp;

            [app.data_user.nUnit,iiUnit] =  unique([UT.data{:,2}]);
            % retrieve the experiment date

            % Add data to table - incöuding default values for fiber width
            % and fiber positioin
            t = cell(numel( app.data_user.nUnit),7);
            for ii = 1 : numel(app.data_user.nUnit)
                t{ii,1} = (app.data_user.nUnit(ii));%seriesID
                t{ii,2} = UT.data{iiUnit(ii),3};%Depth

                t{ii,6} =50;%fiber width
                t{ii,7} ='RW';%fiber position
            end
            app.UITable.Data=t;

        end

        % Callback function
        function dirRawEditFieldValueChanged(app, event)
            temp_dirRaw = dir(app.dirRawEditField.Value);
            temp_dirRaw=temp_dirRaw(~ismember({temp_dirRaw.name},{'.','..'}));
            list_display_string = {temp_dirRaw.name};
            app.ExpIDListBox.Items = list_display_string;

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
% i have commented that part, because I dont think I need to change things
% across table cells
            % % % % % %             dindices = event.Indices;
% % % % % %             t =   app.UITable.Data;
% % % % % %             for ii = 1 : size(dindices,1)
% % % % % %                 tempInd = dindices(ii,:);% I am not sure why this is happening...  Somehow I can not use that as indexing
% % % % % %                 % choose the correct collumns
% % % % % %                 if tempInd(2)~=1 &  tempInd(2)~=2
% % % % % %                     valueFirst = dindices(1,:);
% % % % % %                     t{tempInd(1),tempInd(2)}= app.UITable.Data{valueFirst(1),valueFirst(2)};
% % % % % %                 end
% % % % % %             end
% % % % % %             app.UITable.Data=t;

        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)
            if status_cache == 1
                warning('Disable cache - data are not exported')
                return
            end

            app.data_user.out.Units = [app.UITable.Data{:,1}];

            app.data_user.out.Species = app.SpeciesEditField.Value;
            app.data_user.out.Age = app.AgeEditField.Value;
            app.data_user.out.Weight = app.WeightEditField.Value;
            app.data_user.out.Sex = app.SexEditField.Value;
            app.data_user.out.Opsin = app.OpsinEditField.Value;
            app.data_user.out.VirusFullName = app.VirusFullNameEditField.Value;
            app.data_user.out.VirusNumber = app.VirusNumberEditField.Value;
            app.data_user.out.InjDate = app.InjDateEditField.Value;
            app.data_user.out.DoB = app.DoBEditField.Value;
            app.data_user.out.Genotype = app.GenotypeEditField.Value;
            app.data_user.out.Strain = app.StrainEditField.Value;
            app.data_user.out.ticklabID = app.ticklabIDEditField.Value;
            app.data_user.out.Experimenter = app.ExperimenterEditField.Value;
            app.data_user.out.Anesthesia = app.AnesthesiaEditField.Value;
            app.data_user.out.InjMethod = app.InjMethodEditField.Value;
            %
            %             Tday = day(app.data_user.L.dateID_SU)
            %             Tmonth = month(app.data_user.L.dateID_SU)
            %             Tyear = year(app.data_user.L.dateID_SU)
            %
            %             dateValue =  datestr(app.data_user.L.dateID_SU);
            %             timeValue = app.kanamycinInjEditField.Value;
            %             datetimeValue = datetime([dateValue(1:11) ' ' timeValue], 'InputFormat', 'dd-MMM-yyy HH:mm');
            app.data_user.out.oABRthreshold = app.oABRthresholdEditField.Value;

            app.data_user.out.kanamycinInj = app.kanamycinInjEditField.Value;

            %% update the ee
            app.data_user.ee = setExpMetaData(app.data_user.ee,app.data_user.out);
            saveAnex(app.data_user.ee);

            %%
            UTee.fieldNames = app.UITable.ColumnName;
            UTee.data = app.UITable.Data ;

            save(fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),strcat("uiSUee_", app.data_user.ee.ExpID, ".mat")),'UTee');

        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID, app.data_user.ee.ExpID),strcat("uiSUee_", app.data_user.ee.ExpID, ".mat"));
            load(in_dir_name);
            app.UITable.Data = UTee.data ;
            app.UITable.ColumnName = UTee.fieldNames;

            app.SpeciesEditField.Value = app.data_user.ee.ExpMetaData.Species;
            app.AgeEditField.Value = app.data_user.ee.ExpMetaData.Age;
            app.WeightEditField.Value = app.data_user.ee.ExpMetaData.Weight;
            app.SexEditField.Value = app.data_user.ee.ExpMetaData.Sex;
            app.OpsinEditField.Value =  app.data_user.ee.ExpMetaData.Opsin;
            app.VirusFullNameEditField.Value = app.data_user.ee.ExpMetaData.VirusFullName;
            app.VirusNumberEditField.Value = app.data_user.ee.ExpMetaData.VirusNumber;
            app.InjDateEditField.Value =  app.data_user.ee.ExpMetaData.InjDate;
            app.DoBEditField.Value = app.data_user.ee.ExpMetaData.DoB;
            app.GenotypeEditField.Value =  app.data_user.ee.ExpMetaData.Genotype;
            app.StrainEditField.Value = app.data_user.ee.ExpMetaData.Strain;
            app.ticklabIDEditField.Value = app.data_user.ee.ExpMetaData.ticklabID ;
            app.ExperimenterEditField.Value = app.data_user.ee.ExpMetaData.Experimenter ;
            app.AnesthesiaEditField.Value = app.data_user.ee.ExpMetaData.Anesthesia;
            app.InjMethodEditField.Value =  app.data_user.ee.ExpMetaData.InjMethod ;
            app.kanamycinInjEditField.Value =  app.data_user.ee.ExpMetaData.kanamycinInj ;
            app.oABRthresholdEditField.Value =  app.data_user.ee.ExpMetaData.oABRthreshold ;


        end

        % Callback function
        function checkButtonPushed(app, event)
            'add the tests';
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            display ("uiSU (ee) : a user interface to assign information on the raw data. The user must assign manually differnet units, depth,electrode impledence, electrode changing events, penetrations, coordinates, filter optical density, current and assesment" )
        end

        % Value changed function: DoBEditField
        function DoBEditFieldValueChanged(app, event)
            app.data_user.out.DoB = datetime(app.DoBEditField.Value); % change the
            app.AgeEditField.Value = num2str(abs(round(days(duration ( datetime(app.DoBEditField.Value) - app.data_user.L.dateID_SU ))))) ;

        end

        % Value changed function: oABRthresholdEditField
        function oABRthresholdEditFieldValueChanged(app, event)
            value = app.oABRthresholdEditField.Value;
            app.data_user.out.oABRthreshold = value ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Unit'; 'Depth'; 'CellType'; 'Assessment'; 'Comments'; 'FiberWidth'; 'FiberPosition'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true true true true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import ';
            app.expNameLabel = uilabel(app.UIFigure);
            app.expNameLabel.Text = 'expName';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.SpeciesEditFieldLabel = uilabel(app.UIFigure);
            app.SpeciesEditFieldLabel.Text = 'Species';
            app.SpeciesEditField = uieditfield(app.UIFigure, 'text');
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'text');
            app.expDateLabel = uilabel(app.UIFigure);
            app.expDateLabel.Text = 'expDate';
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'text');
            app.SexEditFieldLabel = uilabel(app.UIFigure);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.UIFigure, 'text');
            app.OpsinEditFieldLabel = uilabel(app.UIFigure);
            app.OpsinEditFieldLabel.Text = 'Opsin';
            app.OpsinEditField = uieditfield(app.UIFigure, 'text');
            app.VirusFullNameEditFieldLabel = uilabel(app.UIFigure);
            app.VirusFullNameEditFieldLabel.Text = 'VirusFullName';
            app.VirusFullNameEditField = uieditfield(app.UIFigure, 'text');
            app.InjDateEditFieldLabel = uilabel(app.UIFigure);
            app.InjDateEditFieldLabel.Text = 'InjDate';
            app.InjDateEditField = uieditfield(app.UIFigure, 'text');
            app.GenotypeEditFieldLabel = uilabel(app.UIFigure);
            app.GenotypeEditFieldLabel.Text = 'Genotype';
            app.GenotypeEditField = uieditfield(app.UIFigure, 'text');
            app.ticklabIDEditFieldLabel = uilabel(app.UIFigure);
            app.ticklabIDEditFieldLabel.Text = 'tick@lab ID';
            app.ticklabIDEditField = uieditfield(app.UIFigure, 'text');
            app.DoBEditFieldLabel = uilabel(app.UIFigure);
            app.DoBEditFieldLabel.Text = 'DoB';
            app.DoBEditField = uieditfield(app.UIFigure, 'text');
            app.DoBEditField.ValueChangedFcn = createCallbackFcn(app, @DoBEditFieldValueChanged, true);
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.UIFigure, 'text');
            app.StrainEditFieldLabel = uilabel(app.UIFigure);
            app.StrainEditFieldLabel.Text = 'Strain';
            app.StrainEditField = uieditfield(app.UIFigure, 'text');
            app.InjMethodEditFieldLabel = uilabel(app.UIFigure);
            app.InjMethodEditFieldLabel.Text = 'InjMethod';
            app.InjMethodEditField = uieditfield(app.UIFigure, 'text');
            app.AnesthesiaEditFieldLabel = uilabel(app.UIFigure);
            app.AnesthesiaEditFieldLabel.Text = 'Anesthesia';
            app.AnesthesiaEditField = uieditfield(app.UIFigure, 'text');
            app.VirusNumberEditFieldLabel = uilabel(app.UIFigure);
            app.VirusNumberEditFieldLabel.Text = 'VirusNumber';
            app.VirusNumberEditField = uieditfield(app.UIFigure, 'text');
            app.kanamycinInjLabel = uilabel(app.UIFigure);
            app.kanamycinInjLabel.Text = 'kanamycinInj';
            app.kanamycinInjEditField = uieditfield(app.UIFigure, 'text');
            app.oABRthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.oABRthresholdEditFieldLabel.Text = 'oABR threshold';
            app.oABRthresholdEditField = uieditfield(app.UIFigure, 'text');
            app.oABRthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @oABRthresholdEditFieldValueChanged, true);
            app.unitFSLButton = uibutton(app.UIFigure, 'push');
            app.unitFSLButton.Text = 'unitFSL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uiSUee(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end