classdef userberabrOD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label         matlab.ui.control.Label
        importButton  matlab.ui.control.Button
        EXPORTButton  matlab.ui.control.Button
        UITable       matlab.ui.control.Table
    end

    properties (Access = public)
        data_user % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ee)

            L = listBerabr(ee);
            L.ABR_SeriesID;
            app.Label.Text = ee.ExpID;
            app.data_user.ee = ee;
            app.data_user.L = L;
            app.data_user.SeriesIDList = app.data_user.L.ABR_SeriesID;

            % harvest the values from the Feathers
            stimModality = cell(numel(app.data_user.SeriesIDList),1,1);
                        stimulusHardware = cell(numel(app.data_user.SeriesIDList),1,1);

            exp_Type = cell(numel(app.data_user.SeriesIDList),1,1);

            for ii = 1 : numel(app.data_user.SeriesIDList)
                B = loadBerabr(berabr (ee,app.data_user.SeriesIDList(ii)));
                stimulusHardware{ii} = B.Stim.stimulusHardware;

                stimModality{ii} = B.Stim.modality;
                exp_protocol{ii} = B.Stim.protocol;
                exp_mode{ii} = B.Stim.mode;

            end

            % Add data to table
            t = cell(numel( app.data_user.SeriesIDList ),5);
            for ii = 1 : numel(app.data_user.SeriesIDList)
                t{ii,1} = char(app.data_user.SeriesIDList(ii));%seriesID
                t{ii,2} = nan;%filterOD
                t{ii,3} = stimulusHardware{ii};

                t{ii,4} = stimModality{ii};
                t{ii,5} = exp_protocol{ii};
                t{ii,6} = exp_mode{ii};
            end
            app.UITable.Data=t;

        end

        % Callback function
        function dirRawEditFieldValueChanged(app, event)
            temp_dirRaw = dir(app.dirRawEditField.Value);
            temp_dirRaw=temp_dirRaw(~ismember({temp_dirRaw.name},{'.','..'}));
            list_display_string = {temp_dirRaw.name};
            app.ExpIDListBox.Items = list_display_string;

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            dindices = event.Indices;
            t =   app.UITable.Data;
            for ii = 1 : size(dindices,1)
                tempInd = dindices(ii,:);% I am not sure why this is happening...  Somehow I can not use that as indexing
                valueFirst = dindices(1,:);
                t{tempInd(1),tempInd(2)}= app.UITable.Data{valueFirst(1),valueFirst(2)};
            end
            app.UITable.Data=t;

        end

        % Button pushed function: EXPORTButton
        function EXPORTButtonPushed(app, event)

            UT.data = app.UITable.Data;
            UT.fieldNames = app.UITable.ColumnName;
            save(fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),strcat("ODui_", app.data_user.ee.ExpID, ".mat")),'UT');

        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            in_dir_name = fullfile( expProcDataDir(app.data_user.ee.ExperimenterID  ,app.data_user.ee.ExpID),strcat("ODui_", app.data_user.ee.ExpID, ".mat"));
            load(in_dir_name);
            app.UITable.Data=UT.data ;
            app.UITable.ColumnName = UT.fieldNames;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'SeriesID'; 'ftOD'; 'hardware'; 'stimModality'; 'protocol'; 'stimMode'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.EXPORTButton = uibutton(app.UIFigure, 'push');
            app.EXPORTButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTButtonPushed, true);
            app.EXPORTButton.Text = 'EXPORT';
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import ';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userberabrOD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end